classdef MultTransITPCEyeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PPCCkb                matlab.ui.control.CheckBox
        TransTypeLabel_29     matlab.ui.control.Label
        TransTypeLabel_28     matlab.ui.control.Label
        SaveRawCkb            matlab.ui.control.CheckBox
        NPermEdit             matlab.ui.control.NumericEditField
        NormLowLabel_4        matlab.ui.control.Label
        TransTypeLabel_27     matlab.ui.control.Label
        TransTypeLabel_26     matlab.ui.control.Label
        TransTypeLabel_25     matlab.ui.control.Label
        TransTypeLabel_24     matlab.ui.control.Label
        TransTypeLabel_23     matlab.ui.control.Label
        TransTypeLabel_22     matlab.ui.control.Label
        TransTypeLabel_21     matlab.ui.control.Label
        TransTypeLabel_20     matlab.ui.control.Label
        FullWalkNormCkb       matlab.ui.control.CheckBox
        AlirezaFixCkb         matlab.ui.control.CheckBox
        TransTypeLabel_19     matlab.ui.control.Label
        Plot2DFreqRng         matlab.ui.control.EditField
        CLimdBLabel_8         matlab.ui.control.Label
        Plot2DBtn             matlab.ui.control.Button
        ShowHTOverlayBtn      matlab.ui.control.Button
        ShowFixOverlayBtn     matlab.ui.control.Button
        YLimEdit              matlab.ui.control.EditField
        CLimdBLabel_7         matlab.ui.control.Label
        RmFixOverlapCkb       matlab.ui.control.CheckBox
        SaccadeFlag           matlab.ui.control.CheckBox
        PixelCkb              matlab.ui.control.CheckBox
        ShuffleCkb            matlab.ui.control.CheckBox
        TransTypeLabel_18     matlab.ui.control.Label
        RmOverlapCkb          matlab.ui.control.CheckBox
        DescCkb               matlab.ui.control.CheckBox
        VelCkb                matlab.ui.control.CheckBox
        WalkCkb               matlab.ui.control.CheckBox
        PatientCkb            matlab.ui.control.CheckBox
        RegionCkb             matlab.ui.control.CheckBox
        TransCkb              matlab.ui.control.CheckBox
        SelDirEdit            matlab.ui.control.EditField
        SaveDirLabel          matlab.ui.control.Label
        TransTypeLabel_16     matlab.ui.control.Label
        TransWinEdit          matlab.ui.control.EditField
        CLimdBLabel_6         matlab.ui.control.Label
        SaveFigCkb            matlab.ui.control.CheckBox
        SaveBtn               matlab.ui.control.Button
        CLim                  matlab.ui.control.EditField
        CLimdBLabel_2         matlab.ui.control.Label
        PvalEdit              matlab.ui.control.NumericEditField
        NormLowLabel_3        matlab.ui.control.Label
        PlotButton            matlab.ui.control.Button
        TransTypeDD           matlab.ui.control.DropDown
        DescTypeDD            matlab.ui.control.DropDown
        RegionTypeDD          matlab.ui.control.DropDown
        PatientTypeDD         matlab.ui.control.DropDown
        TransTypeLabel_8      matlab.ui.control.Label
        WalkTypeDD            matlab.ui.control.DropDown
        TransTypeLabel_5      matlab.ui.control.Label
        TransTypeLabel_4      matlab.ui.control.Label
        TransTypeLabel_2      matlab.ui.control.Label
        VelTypeDD             matlab.ui.control.DropDown
        OverlapTransTypeDD    matlab.ui.control.DropDown
        OverlapDescTypeDD     matlab.ui.control.DropDown
        FixDurDD              matlab.ui.control.DropDown
        TransTypeDD_2         matlab.ui.control.DropDown
        DescTypeDD_2          matlab.ui.control.DropDown
        RegionTypeDD_2        matlab.ui.control.DropDown
        PatientTypeDD_2       matlab.ui.control.DropDown
        WalkTypeDD_2          matlab.ui.control.DropDown
        VelTypeDD_2           matlab.ui.control.DropDown
        OverlapTransTypeDD_2  matlab.ui.control.DropDown
        OverlapDescTypeDD_2   matlab.ui.control.DropDown
        FixDurDD_2            matlab.ui.control.DropDown
        DifferenceCkb         matlab.ui.control.CheckBox
        TransTypeLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        RWA % realworld analysis object
        fH; %itpc
        fH2D; %2D itpc
        aH2D;
        CustomRegion1;
        CustomRegion2;
        aH1; %axis handle to itpc
        aH2; %axis handle to evoked potential
        pH; %fixation overlay
        pH2; %head turn overlay
        pH3; %fband overlay
        FixOverlay=false;
        HTOverlay=false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin>1
                app.RWA = varargin{1};
                if isempty(app.RWA.MultTrans)
                    uialert(app.UIFigure,'Run getMultTransData first!','warning');
                    return;
                end
            else
                WaitH = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Loading...');
                drawnow;
                pause(0.1);
                app.RWA = RWAnalysis2;
                app.RWA.loadMultData;
                app.RWA.updateMultTableEye;
                close(WaitH);
                if isempty(app.RWA.MultTrans)
                    uialert(app.UIFigure,'RWAnalysis.mat file not found!','warning');
                    return;
                end
            end
            
            %transitions
            eventlist = unique(app.RWA.MultTrans.Evnt);
            app.TransTypeDD.Items = [{''},cellstr(eventlist)]; %app.TransTypeDD.ValueIndex = 1;
            app.TransTypeDD_2.Items = [{''},cellstr(eventlist)]; %app.TransTypeDD_2.ValueIndex = 1;

            %overlap transitions (to be removed if overlap checkbox is checked)
            app.OverlapTransTypeDD.Items = [{''},cellstr(eventlist)]; 
            app.OverlapTransTypeDD_2.Items = [{''},cellstr(eventlist)]; 

            %descriptions
            desclist = regexp(app.RWA.MultTrans.Desc,'\[([a-zA-Z0-9]+)\]','tokens');
            desclist(cellfun(@isempty,desclist)) = [];
            desclist = cellfun(@(x)[x{:}],desclist,'UniformOutput',false);
            desclist = unique(cat(2,desclist{:}));
            app.DescTypeDD.Items = [{''},cellstr(desclist)]; %app.DescTypeDD.ValueIndex = 1;
            app.DescTypeDD_2.Items = [{''},cellstr(desclist)]; %app.DescTypeDD_2.ValueIndex = 1;

            %overlap transition descriptions (to be removed if overlap checkbox is checked)
            app.OverlapDescTypeDD.Items = [{''},cellstr(desclist)]; 
            app.OverlapDescTypeDD_2.Items = [{''},cellstr(desclist)]; 

            %regions
            regionlist = {'AntHipp','LatTemp','Ent+Peri','PostHipp+Para','All Chans','Custom'};
            app.RegionTypeDD.Items = [{''},regionlist]; %app.RegionTypeDD.ValueIndex = 1;
            app.RegionTypeDD_2.Items = [{''},regionlist]; %app.RegionTypeDD_2.ValueIndex = 1;

            %patient
            patientlist = {'All Patients','Leave Out 1','Leave Out 2','Leave Out 3','Leave Out 4','Leave Out 5','1','2','3','4','5'};
            app.PatientTypeDD.Items = [{''},patientlist]; app.PatientTypeDD.Value = 'All Patients';
            app.PatientTypeDD_2.Items = [{''},patientlist]; app.PatientTypeDD_2.Value = 'All Patients';

            %walk
            walklist = {'First Walks','Last Walks','Stop Walks','Go Walks','All Walks','1','2','3','4','5','6','7','8'};
            app.WalkTypeDD.Items = [{''},walklist]; app.WalkTypeDD.Value = 'All Walks';
            app.WalkTypeDD_2.Items = [{''},walklist]; app.WalkTypeDD_2.Value = 'All Walks';

            %velocity
            vellist = {'VelHigh','VelLow','VelHighTercile','VelMidTercile','VelLowTercile','VelHigh10','VelLow10'};
            app.VelTypeDD.Items = [{''},vellist]; app.VelTypeDD.Value = '';
            app.VelTypeDD_2.Items = [{''},vellist]; app.VelTypeDD_2.Value = '';

            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            transtype = app.TransTypeDD.Value;
            desctype = app.DescTypeDD.Value;
            regiontype = app.RegionTypeDD.Value;
            patienttype = app.PatientTypeDD.Value;
            walktype = app.WalkTypeDD.Value;
            veltype = app.VelTypeDD.Value;
            customregion = app.CustomRegion1;
            overlapevnts = app.OverlapTransTypeDD.Value;
            overlapdesc = app.OverlapDescTypeDD.Value;
            itpcfixdur = app.FixDurDD.Value;
            ppcflag = app.PPCCkb.Value;

            transrng = str2num(app.TransWinEdit.Value);
            pval = app.PvalEdit.Value;
            rmoverlap = app.RmOverlapCkb.Value; %remove overlapping trials
            shuffleflag = app.ShuffleCkb.Value; 
            permflag = true; %always do permutations
            nperm = app.NPermEdit.Value;
            if app.PixelCkb.Value
                correctiontype = 'pixel';
            else
                correctiontype = 'fdr';
            end
            saccadeflag = app.SaccadeFlag.Value;
            rmfixoverlap = app.RmFixOverlapCkb.Value;
            alirezafix = app.AlirezaFixCkb.Value;
            fullwalknorm = app.FullWalkNormCkb.Value;
           
            b = [];
            % b(1) = isempty(transtype) && ~app.TransCkb.Value;
            b(2) = isempty(regiontype) && ~app.RegionCkb.Value;
            b(3) = isempty(patienttype) && ~app.PatientCkb.Value;
            b(4) = isempty(walktype) && ~app.WalkCkb.Value;
            b(5) = strcmp(regiontype,'Custom') && isempty(customregion);

            transtype2 = app.TransTypeDD_2.Value;
            desctype2 = app.DescTypeDD_2.Value;
            regiontype2 = app.RegionTypeDD_2.Value;
            patienttype2 = app.PatientTypeDD_2.Value;
            walktype2 = app.WalkTypeDD_2.Value;
            veltype2 = app.VelTypeDD_2.Value;
            customregion2 = app.CustomRegion2;
            overlapevnts2 = app.OverlapTransTypeDD_2.Value;
            overlapdesc2 = app.OverlapDescTypeDD_2.Value;
            itpcfixdur2 = app.FixDurDD_2.Value;

            b2 = [];
            b2(1) = isempty(transtype2);
            b2(2) = isempty(regiontype2);
            b2(3) = isempty(patienttype2);
            b2(4) = isempty(walktype2);
            b2(5) = strcmp(regiontype2,'Custom') && isempty(customregion2);

            %Perform some checks
            if any(b)
                uialert(app.UIFigure,'Make sure to select all options!','error');
                return;
            end

            if app.DifferenceCkb.Value
                if any(b2)
                    uialert(app.UIFigure,'Make sure to select all options on the right!','error');
                    return;
                end
            end

            %Plot
            if app.DifferenceCkb.Value
                pb = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Generating plot...'); pb.Value = 1;
                app.fH = app.RWA.plotMultTransITPCEyeDiff('transtype',{transtype,transtype2},'regiontype',{regiontype,regiontype2},...
                        'walktype',{walktype,walktype2},'clim',str2num(app.CLim.Value),...
                        'desctype',{desctype,desctype2},'patienttype',{patienttype,patienttype2},'pval',pval,'transrng',transrng,...
                        'veltype',{veltype,veltype2},...
                        'customregion',{customregion,customregion2},'rmoverlap',rmoverlap,...
                        'overlapevnts',{overlapevnts,overlapevnts2},'shuffleflag',shuffleflag,...
                        'permflag',permflag,'correctiontype',correctiontype,'saccadeflag',saccadeflag,...
                        'ylim',str2num(app.YLimEdit.Value),'rmfixoverlap',rmfixoverlap,...
                        'itpcfixdur',{itpcfixdur,itpcfixdur2},'alirezafix',alirezafix,...
                        'fullwalknorm',fullwalknorm,'nperm',nperm,'overlapdesc',{overlapdesc,overlapdesc2});
                app.aH1 = app.fH.UserData.aH1;
                app.aH2 = app.fH.UserData.aH2;
                app.pH = app.fH.UserData.pH;
                app.pH2 = app.fH.UserData.pH2;
                app.FixOverlay = false;
                app.HTOverlay = false;
                close(pb);
                drawnow; pause(0.1);

            else
                if app.TransCkb.Value
                    transcell = app.TransTypeDD.Items(2:end);
                else
                    transcell = {transtype};
                end
                if app.RegionCkb.Value
                    regioncell = app.RegionTypeDD.Items(2:end-1);
                else
                    regioncell = {regiontype};
                end
                if app.PatientCkb.Value
                    patientcell = app.PatientTypeDD.Items(2:end);
                else
                    patientcell = {patienttype};
                end
                if app.WalkCkb.Value
                    walkcell = app.WalkTypeDD.Items(2:end);
                else
                    walkcell = {walktype};
                end
                if app.VelCkb.Value
                    velcell = app.VelTypeDD.Items(2:end);
                else
                    velcell = {veltype};
                end

                ntrans = length(transcell);
                nregion = length(regioncell);
                npatient = length(patientcell);
                nwalk = length(walkcell);
                nvel = length(velcell);
                ntotal = ntrans*nregion*npatient*nwalk*nvel;

                [tidx,ridx,pidx,widx,vidx] = ndgrid(1:ntrans,1:nregion,1:npatient,1:nwalk,1:nvel);
                idx = [tidx(:),ridx(:),pidx(:),widx(:),vidx(:)];

                if size(idx,1)~=ntotal
                    uialert(app.UIFigure,'Iteration size mismatch!','error');
                    return;
                end

                iterflag = any([ntrans,nregion,npatient,nwalk,nvel,ntotal]>1);
                if iterflag && (app.SaveFigCkb.Value || app.SaveRawCkb.Value)
                    uialert(app.UIFigure,'Uncheck "Raw" or "Fig"','error');
                    return;
                end

                if iterflag
                    sel = uiconfirm(app.UIFigure,sprintf('%d iterations will be performed and saved. Continue?',ntotal),'Continue?');
                    switch sel
                        case 'Cancel'
                            return;
                    end
                end

                pb = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Generating plot...');
                for k=1:ntotal
                    pb.Value = (k/ntotal);

                    if ntotal>1
                        if any(ishandle(app.fH)); close(app.fH); end
                    end
                    app.fH = app.RWA.plotMultTransITPCEye('transtype',transcell{idx(k,1)},'regiontype',regioncell{idx(k,2)},...
                        'walktype',walkcell{idx(k,4)},'clim',str2num(app.CLim.Value),...
                        'desctype',desctype,'patienttype',patientcell{idx(k,3)},'pval',pval,'transrng',transrng,...
                        'veltype',velcell{idx(k,5)},...
                        'customregion',customregion,'rmoverlap',rmoverlap,...
                        'overlapevnts',overlapevnts,'shuffleflag',shuffleflag,...
                        'permflag',permflag,'correctiontype',correctiontype,'saccadeflag',saccadeflag,...
                        'ylim',str2num(app.YLimEdit.Value),'rmfixoverlap',rmfixoverlap,...
                        'itpcfixdur',itpcfixdur,'alirezafix',alirezafix,...
                        'fullwalknorm',fullwalknorm,'nperm',nperm,'overlapdesc',overlapdesc,...
                        'ppcflag',ppcflag);
                    app.aH1 = app.fH.UserData.aH1;
                    app.aH2 = app.fH.UserData.aH2;
                    app.pH = app.fH.UserData.pH;
                    app.pH2 = app.fH.UserData.pH2;
                    app.FixOverlay = false;
                    app.HTOverlay = false;
                    drawnow; pause(0.1);
                    if iterflag
                        app.SaveBtnFcn;
                    end
                end
                close(pb);
            end


        end

        % Button pushed function: SaveBtn
        function SaveBtnFcn(app, event)
            if any(ishandle(app.fH))
                app.SaveBtn.BackgroundColor = 'r';
                if isempty(app.SelDirEdit.Value)
                    app.SelDirEdit.Value = lastDir('Choose the destination folder...');
                end
                if ~isfolder(app.SelDirEdit.Value)
                    mkdir(app.SelDirEdit.Value);
                end
                tH = get(app.aH1(end),'title');
                tStr = tH.String;
                tStr = regexprep(tStr,'\s+','');
                tStr = regexprep(tStr,'\\|/','-');
                tStr = regexprep(tStr,'<','');
                tStr = regexprep(tStr,'::','~');
                tStr = regexprep(tStr,'\|','+');
                tStr = regexprep(tStr,'\(',',');
                tStr = regexprep(tStr,'\)','');
                tStr = regexprep(tStr,'=','');
                tStr = cell2mat(reshape(tStr,1,[]));
                fstr = sprintf('ITPCGUI_%s.png',tStr);
                print(app.fH,fullfile(app.SelDirEdit.Value,fstr),'-dpng','-r300');
                if app.SaveFigCkb.Value
                    savefig(app.fH,fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','fig')))
                end
                if app.SaveRawCkb.Value
                    MultEye = app.RWA.MultEye;
                    ITPC = app.RWA.ITPC;
                    save(fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','mat')),"MultEye","ITPC",'-v7.3')
                end
                if ishandle(app.fH2D)
                    fstr2 = sprintf('ITPC2D_%s.png',tStr);
                    print(app.fH2D,fullfile(app.SelDirEdit.Value,fstr2),'-dpng','-r300');
                    if app.SaveFigCkb.Value
                        savefig(app.fH2D,fullfile(app.SelDirEdit.Value,regexprep(fstr2,'png$','fig')))
                    end
                end
                app.SaveBtn.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: RegionTypeDD
        function RegionTypeFcn(app, event)
            if strcmp(app.RegionTypeDD.Value,'Custom')
                CustomRegionGUI(app,1);
            end            
        end

        % Value changed function: RegionTypeDD_2
        function RegionTypeDDFcn_2(app, event)
            if strcmp(app.RegionTypeDD_2.Value,'Custom')
                CustomRegionGUI(app,2);
            end  
        end

        % Value changed function: CLim
        function CLimFcn(app, event)
            if any(ishandle(app.aH1))
                set(app.aH1,'clim',str2num(app.CLim.Value))
            end
        end

        % Value changed function: YLimEdit
        function YLimEditValueChanged(app, event)
            if any(ishandle(app.aH2))
                set(app.aH2,'ylim',str2num(app.YLimEdit.Value))
            end
        end

        % Button pushed function: ShowFixOverlayBtn
        function ShowFixOverlayBtnButtonPushed(app, event)
            if app.FixOverlay
                set(app.pH,'visible','off');
                app.FixOverlay = false;
                if ~app.HTOverlay
                    app.aH2.YAxis(2).Visible = 'off';
                end
            else
                set(app.pH,'visible','on');
                app.aH2.YAxis(2).Visible = 'on';
                app.FixOverlay = true;
            end
        end

        % Button pushed function: ShowHTOverlayBtn
        function ShowHTOverlayBtnButtonPushed(app, event)
            if app.HTOverlay
                set(app.pH2,'visible','off');
                app.HTOverlay = false;
                if ~app.FixOverlay
                    app.aH2.YAxis(2).Visible = 'off';
                end
            else
                set(app.pH2,'visible','on');
                app.aH2.YAxis(2).Visible = 'on';
                app.HTOverlay = true;
            end
        end

        % Button pushed function: Plot2DBtn
        function Plot2DFcn(app, event)
            if ishandle(app.fH)
                f = app.RWA.ITPC.f;
                tsec = app.RWA.ITPC.tsec;
                frng = str2num(app.Plot2DFreqRng.Value);
                pval = app.RWA.ITPC.pval;
                correctiontype = app.RWA.ITPC.correctiontype;
                nperm = app.RWA.ITPC.nperm;
                ppcflag = app.RWA.ITPC.ppcflag;
                for k=1:length(frng)
                    set(app.fH.UserData.pH3(k),'YData',[frng(k),frng(k)]);
                end
                set(app.fH.UserData.pH3,'Visible','on');
                fidx = f>=frng(1) & f<=frng(2);
                itpc_cfs = app.RWA.ITPC.itpc_cfs(:,fidx,:); %time x freq x trials (complex double, normalized)
                ntrials = size(itpc_cfs,3);

                itpc = mean(itpc_cfs,3); %mean across trials in complex
                itpc = abs(itpc); %inter-trial phase coherence -> same as plv but mean across trials (no angle difference)
                if ppcflag
                    itpc = (ntrials.*itpc.^2-1)./(ntrials-1);
                end
                itpc = mean(itpc,2); %mean across freq in real

                %These have already been converted to PPC
                PM = squeeze(mean(app.RWA.ITPC.PM(:,fidx,:),2));
                mPM = squeeze(mean(app.RWA.ITPC.mPM(:,fidx),2));
                sPM = squeeze(mean(app.RWA.ITPC.sPM(:,fidx),2));

                zITPC = (itpc-mPM)./sPM;
                zPM = (PM-mPM)./sPM;

                zITPC_thresh = zITPC;
                switch correctiontype
                    case 'pixel'
                        % max-statistic correction across time/frequency
                        maxAbs = squeeze(max(abs(zPM),[],1));
                        zcrit = prctile(maxAbs, 100*(1-pval));
                        zITPC_thresh(abs(zITPC) < zcrit) = 0;
                    case 'fdr'
                        % empirical two-tailed permutation p-values
                        pUpper = (1 + sum(PM >= itpc, 2)) ./ (nperm + 1);
                        pLower = (1 + sum(PM <= itpc, 2)) ./ (nperm + 1);
                        PV = min(1, 2 .* min(pUpper, pLower));
                        QV = reshape(mafdr(PV(:),'BHFDR',true), size(PV));
                        zITPC_thresh(QV >= pval) = 0;
                end

                itpc_band_boot = calcRWAITPCBootstrap_mex(itpc_cfs,ppcflag);

                %Uses BCa to compute 95CI to avoid real falling outside 95CI, but takes way too long!!
                % [itpc2,itpc_band_boot2] = calcRWAITPCBootstrap2(itpc_cfs);

                %logit transform can be applied to itpc to make a more
                %normal? not going to worry about this issue for now
                % eps0 = 1e-6;
                % z = @(p) log((p+eps0)./(1-p+eps0));
                % zinv = @(x) 1 ./ (1 + exp(-x));  % logistic, equivalent to 1/(1+exp(-x))
                % z_boot = z(itpc_band_boot);          % time x B
                % ci_z = zinv(prctile(z_boot, [2.5 97.5], 2)');   % time x 2

                ci = prctile(itpc_band_boot, [2.5 97.5], 2)';

                ci_min_max = prctile(ci(:),[0,100]);
                sig_bar_y = (ci_min_max(2) + diff(ci_min_max)*0.1).*(zITPC_thresh>0);
                sig_bar_y(sig_bar_y==0) = nan;

                app.fH2D = figure;
                app.aH2D = axes('parent',app.fH2D);
                plot(app.aH2D,tsec,mean(itpc_band_boot,2),'b');
                hold(app.aH2D,'on');
                plot(app.aH2D,tsec,sig_bar_y,'k','LineWidth',2);
                patch(app.aH2D,[tsec,fliplr(tsec)],[ci(1,:),fliplr(ci(2,:))],'b','facealpha',0.3,'edgecolor','none');
                xlim(app.aH2D,[tsec(1),tsec(end)])
                if ppcflag
                    title(app.aH2D,sprintf('PPC (%0.0f to %0.0f Hz)',frng(1),frng(2)))
                    ylabel(app.aH2D,'PPC')
                else
                    title(app.aH2D,sprintf('ITPC (%0.0f to %0.0f Hz)',frng(1),frng(2)))
                    ylabel(app.aH2D,'ITPC')
                end
                xlabel(app.aH2D,'sec')
            end
        end

        % Value changed function: Plot2DFreqRng
        function Plot2DFreqRngValueChanged(app, event)
            if ishandle(app.fH)
                frng = str2num(app.Plot2DFreqRng.Value);
                for k=1:length(frng)
                    set(app.fH.UserData.pH3(k),'YData',[frng(k),frng(k)]);
                end
                set(app.fH.UserData.pH3,'Visible','on');
            end
        end

        % Value changed function: DifferenceCkb
        function DifferenceCkbValueChanged(app, event)
            if app.DifferenceCkb.Value
                app.TransTypeDD_2.Enable = 'on';
                app.DescTypeDD_2.Enable = 'on';
                app.RegionTypeDD_2.Enable = 'on';
                app.PatientTypeDD_2.Enable = 'on';
                app.WalkTypeDD_2.Enable = 'on';
                app.VelTypeDD_2.Enable = 'on';
                app.OverlapTransTypeDD_2.Enable = 'on';
                app.FixDurDD_2.Enable = 'on';
            else
                app.TransTypeDD_2.Enable = 'off';
                app.DescTypeDD_2.Enable = 'off';
                app.RegionTypeDD_2.Enable = 'off';
                app.PatientTypeDD_2.Enable = 'off';
                app.WalkTypeDD_2.Enable = 'off';
                app.VelTypeDD_2.Enable = 'off';
                app.OverlapTransTypeDD_2.Enable = 'off';
                app.FixDurDD_2.Enable = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TransTypeLabel = uilabel(app.UIFigure);
            app.TransTypeLabel.Text = 'Transition';
            app.DifferenceCkb = uicheckbox(app.UIFigure);
            app.DifferenceCkb.ValueChangedFcn = createCallbackFcn(app, @DifferenceCkbValueChanged, true);
            app.DifferenceCkb.Text = 'Difference';
            app.FixDurDD_2 = uidropdown(app.UIFigure);
            app.FixDurDD_2.Items = {'', 'High', 'Low'};
            app.FixDurDD_2.Editable = 'on';
            app.FixDurDD_2.Enable = 'off';
            app.FixDurDD_2.Tooltip = {'Remove any trials where the overlap transition type overlaps with the main transition type.'};
            app.FixDurDD_2.Value = '';
            app.OverlapDescTypeDD_2 = uidropdown(app.UIFigure);
            app.OverlapDescTypeDD_2.Items = {''};
            app.OverlapDescTypeDD_2.Editable = 'on';
            app.OverlapDescTypeDD_2.Enable = 'off';
            app.OverlapDescTypeDD_2.Value = '';
            app.OverlapTransTypeDD_2 = uidropdown(app.UIFigure);
            app.OverlapTransTypeDD_2.Items = {''};
            app.OverlapTransTypeDD_2.Editable = 'on';
            app.OverlapTransTypeDD_2.Enable = 'off';
            app.OverlapTransTypeDD_2.Tooltip = {'Remove any trials where the overlap transition type overlaps with the main transition type.'};
            app.OverlapTransTypeDD_2.Value = '';
            app.VelTypeDD_2 = uidropdown(app.UIFigure);
            app.VelTypeDD_2.Items = {''};
            app.VelTypeDD_2.Editable = 'on';
            app.VelTypeDD_2.Enable = 'off';
            app.VelTypeDD_2.Value = '';
            app.WalkTypeDD_2 = uidropdown(app.UIFigure);
            app.WalkTypeDD_2.Items = {''};
            app.WalkTypeDD_2.Editable = 'on';
            app.WalkTypeDD_2.Enable = 'off';
            app.WalkTypeDD_2.Value = '';
            app.PatientTypeDD_2 = uidropdown(app.UIFigure);
            app.PatientTypeDD_2.Items = {''};
            app.PatientTypeDD_2.Editable = 'on';
            app.PatientTypeDD_2.Enable = 'off';
            app.PatientTypeDD_2.Value = '';
            app.RegionTypeDD_2 = uidropdown(app.UIFigure);
            app.RegionTypeDD_2.Items = {''};
            app.RegionTypeDD_2.Editable = 'on';
            app.RegionTypeDD_2.ValueChangedFcn = createCallbackFcn(app, @RegionTypeDDFcn_2, true);
            app.RegionTypeDD_2.Enable = 'off';
            app.RegionTypeDD_2.Value = '';
            app.DescTypeDD_2 = uidropdown(app.UIFigure);
            app.DescTypeDD_2.Items = {''};
            app.DescTypeDD_2.Editable = 'on';
            app.DescTypeDD_2.Enable = 'off';
            app.DescTypeDD_2.Value = '';
            app.TransTypeDD_2 = uidropdown(app.UIFigure);
            app.TransTypeDD_2.Items = {''};
            app.TransTypeDD_2.Editable = 'on';
            app.TransTypeDD_2.Enable = 'off';
            app.TransTypeDD_2.Value = '';
            app.FixDurDD = uidropdown(app.UIFigure);
            app.FixDurDD.Items = {'', 'High', 'Low'};
            app.FixDurDD.Editable = 'on';
            app.FixDurDD.Tooltip = {'Remove any trials where the overlap transition type overlaps with the main transition type.'};
            app.FixDurDD.Value = '';
            app.OverlapDescTypeDD = uidropdown(app.UIFigure);
            app.OverlapDescTypeDD.Items = {''};
            app.OverlapDescTypeDD.Editable = 'on';
            app.OverlapDescTypeDD.Tooltip = {'| symbol can be added between each description to include more than one'};
            app.OverlapDescTypeDD.Value = '';
            app.OverlapTransTypeDD = uidropdown(app.UIFigure);
            app.OverlapTransTypeDD.Items = {''};
            app.OverlapTransTypeDD.Editable = 'on';
            app.OverlapTransTypeDD.Tooltip = {'Remove any trials where the overlap transition type overlaps with the main transition type.'};
            app.OverlapTransTypeDD.Value = '';
            app.VelTypeDD = uidropdown(app.UIFigure);
            app.VelTypeDD.Items = {''};
            app.VelTypeDD.Editable = 'on';
            app.VelTypeDD.Tooltip = {'These represent the amount of velocity change in +-5sec window centered on transition'};
            app.VelTypeDD.Value = '';
            app.TransTypeLabel_2 = uilabel(app.UIFigure);
            app.TransTypeLabel_2.Text = 'Description (opt)';
            app.TransTypeLabel_4 = uilabel(app.UIFigure);
            app.TransTypeLabel_4.Text = 'Region';
            app.TransTypeLabel_5 = uilabel(app.UIFigure);
            app.TransTypeLabel_5.Text = 'Walk';
            app.WalkTypeDD = uidropdown(app.UIFigure);
            app.WalkTypeDD.Items = {''};
            app.WalkTypeDD.Editable = 'on';
            app.WalkTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.WalkTypeDD.Value = '';
            app.TransTypeLabel_8 = uilabel(app.UIFigure);
            app.TransTypeLabel_8.Text = 'Patient';
            app.PatientTypeDD = uidropdown(app.UIFigure);
            app.PatientTypeDD.Items = {''};
            app.PatientTypeDD.Editable = 'on';
            app.PatientTypeDD.Tooltip = {'A list of patient numbers can be added manually (i.e. 1,3,4)'};
            app.PatientTypeDD.Value = '';
            app.RegionTypeDD = uidropdown(app.UIFigure);
            app.RegionTypeDD.Items = {''};
            app.RegionTypeDD.Editable = 'on';
            app.RegionTypeDD.ValueChangedFcn = createCallbackFcn(app, @RegionTypeFcn, true);
            app.RegionTypeDD.Tooltip = {'| symbol can be added between region names (except custom) to include more than one'};
            app.RegionTypeDD.Value = '';
            app.DescTypeDD = uidropdown(app.UIFigure);
            app.DescTypeDD.Items = {''};
            app.DescTypeDD.Editable = 'on';
            app.DescTypeDD.Tooltip = {'| symbol can be added between each description to include more than one'};
            app.DescTypeDD.Value = '';
            app.TransTypeDD = uidropdown(app.UIFigure);
            app.TransTypeDD.Items = {''};
            app.TransTypeDD.Editable = 'on';
            app.TransTypeDD.Tooltip = {'| symbol can be added between transitions to include more than one'};
            app.TransTypeDD.Value = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NormLowLabel_3 = uilabel(app.UIFigure);
            app.NormLowLabel_3.Text = 'Pval';
            app.PvalEdit = uieditfield(app.UIFigure, 'numeric');
            app.PvalEdit.Limits = [0 1];
            app.PvalEdit.Value = 0.05;
            app.CLimdBLabel_2 = uilabel(app.UIFigure);
            app.CLimdBLabel_2.Text = 'CLim';
            app.CLim = uieditfield(app.UIFigure, 'text');
            app.CLim.ValueChangedFcn = createCallbackFcn(app, @CLimFcn, true);
            app.CLim.Tooltip = {'Color limits in units of zscore for plotting specgram (enter zero to hide specgram)'};
            app.CLim.Value = '-10,10';
            app.SaveBtn = uibutton(app.UIFigure, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnFcn, true);
            app.SaveBtn.Text = 'Save';
            app.SaveFigCkb = uicheckbox(app.UIFigure);
            app.SaveFigCkb.Text = 'Fig';
            app.CLimdBLabel_6 = uilabel(app.UIFigure);
            app.CLimdBLabel_6.Text = 'Window';
            app.TransWinEdit = uieditfield(app.UIFigure, 'text');
            app.TransWinEdit.Tooltip = {'Transition window range in sec'};
            app.TransWinEdit.Value = '-3,3';
            app.TransTypeLabel_16 = uilabel(app.UIFigure);
            app.TransTypeLabel_16.Text = 'Velocity';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'Save Dir';
            app.SelDirEdit = uieditfield(app.UIFigure, 'text');
            app.TransCkb = uicheckbox(app.UIFigure);
            app.TransCkb.Tooltip = {'Iterate and save'};
            app.TransCkb.Text = '';
            app.RegionCkb = uicheckbox(app.UIFigure);
            app.RegionCkb.Tooltip = {'Iterate and save'};
            app.RegionCkb.Text = '';
            app.PatientCkb = uicheckbox(app.UIFigure);
            app.PatientCkb.Tooltip = {'Iterate and save'};
            app.PatientCkb.Text = '';
            app.WalkCkb = uicheckbox(app.UIFigure);
            app.WalkCkb.Tooltip = {'Iterate and save'};
            app.WalkCkb.Text = '';
            app.VelCkb = uicheckbox(app.UIFigure);
            app.VelCkb.Tooltip = {'Iterate and save'};
            app.VelCkb.Text = '';
            app.DescCkb = uicheckbox(app.UIFigure);
            app.DescCkb.Enable = 'off';
            app.DescCkb.Text = '';
            app.RmOverlapCkb = uicheckbox(app.UIFigure);
            app.RmOverlapCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.RmOverlapCkb.Text = 'No Overlap (Trans)';
            app.RmOverlapCkb.Value = true;
            app.TransTypeLabel_18 = uilabel(app.UIFigure);
            app.TransTypeLabel_18.Text = 'Overlap Transitions';
            app.ShuffleCkb = uicheckbox(app.UIFigure);
            app.ShuffleCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.ShuffleCkb.Text = 'Shuffle';
            app.PixelCkb = uicheckbox(app.UIFigure);
            app.PixelCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.PixelCkb.Text = 'Pixel';
            app.SaccadeFlag = uicheckbox(app.UIFigure);
            app.SaccadeFlag.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.SaccadeFlag.Text = 'Saccade';
            app.RmFixOverlapCkb = uicheckbox(app.UIFigure);
            app.RmFixOverlapCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.RmFixOverlapCkb.Text = 'No Overlap (Fix)';
            app.CLimdBLabel_7 = uilabel(app.UIFigure);
            app.CLimdBLabel_7.Text = 'YLim';
            app.YLimEdit = uieditfield(app.UIFigure, 'text');
            app.YLimEdit.ValueChangedFcn = createCallbackFcn(app, @YLimEditValueChanged, true);
            app.YLimEdit.Tooltip = {'Color limits in units of zscore for plotting specgram (enter zero to hide specgram)'};
            app.YLimEdit.Value = '-20,20';
            app.ShowFixOverlayBtn = uibutton(app.UIFigure, 'push');
            app.ShowFixOverlayBtn.ButtonPushedFcn = createCallbackFcn(app, @ShowFixOverlayBtnButtonPushed, true);
            app.ShowFixOverlayBtn.Text = 'Show Fix';
            app.ShowHTOverlayBtn = uibutton(app.UIFigure, 'push');
            app.ShowHTOverlayBtn.ButtonPushedFcn = createCallbackFcn(app, @ShowHTOverlayBtnButtonPushed, true);
            app.ShowHTOverlayBtn.Text = 'Show HT';
            app.Plot2DBtn = uibutton(app.UIFigure, 'push');
            app.Plot2DBtn.ButtonPushedFcn = createCallbackFcn(app, @Plot2DFcn, true);
            app.Plot2DBtn.Tooltip = {'Plots 2D itpc. Must be run after main plot is generated!'};
            app.Plot2DBtn.Text = 'Plot 2D';
            app.CLimdBLabel_8 = uilabel(app.UIFigure);
            app.CLimdBLabel_8.Text = 'Freq Rng';
            app.Plot2DFreqRng = uieditfield(app.UIFigure, 'text');
            app.Plot2DFreqRng.ValueChangedFcn = createCallbackFcn(app, @Plot2DFreqRngValueChanged, true);
            app.Plot2DFreqRng.Tooltip = {'Comma separated freq range for 2D itpc plot in Hz'};
            app.Plot2DFreqRng.Value = '2,6';
            app.TransTypeLabel_19 = uilabel(app.UIFigure);
            app.TransTypeLabel_19.Text = 'Fixation Duration';
            app.AlirezaFixCkb = uicheckbox(app.UIFigure);
            app.AlirezaFixCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.AlirezaFixCkb.Text = 'Alireza';
            app.AlirezaFixCkb.Value = true;
            app.FullWalkNormCkb = uicheckbox(app.UIFigure);
            app.FullWalkNormCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.FullWalkNormCkb.Text = 'Full Walk';
            app.FullWalkNormCkb.Value = true;
            app.TransTypeLabel_20 = uilabel(app.UIFigure);
            app.TransTypeLabel_20.Text = 'Transition';
            app.TransTypeLabel_21 = uilabel(app.UIFigure);
            app.TransTypeLabel_21.Text = 'Description (opt)';
            app.TransTypeLabel_22 = uilabel(app.UIFigure);
            app.TransTypeLabel_22.Text = 'Region';
            app.TransTypeLabel_23 = uilabel(app.UIFigure);
            app.TransTypeLabel_23.Text = 'Walk';
            app.TransTypeLabel_24 = uilabel(app.UIFigure);
            app.TransTypeLabel_24.Text = 'Patient';
            app.TransTypeLabel_25 = uilabel(app.UIFigure);
            app.TransTypeLabel_25.Text = 'Velocity';
            app.TransTypeLabel_26 = uilabel(app.UIFigure);
            app.TransTypeLabel_26.Text = 'Overlap Transitions';
            app.TransTypeLabel_27 = uilabel(app.UIFigure);
            app.TransTypeLabel_27.Text = 'Fixation Duration';
            app.NormLowLabel_4 = uilabel(app.UIFigure);
            app.NormLowLabel_4.Text = 'Nperm';
            app.NPermEdit = uieditfield(app.UIFigure, 'numeric');
            app.NPermEdit.Limits = [1000 5000];
            app.NPermEdit.Tooltip = {'Number of permutations. This should be 2000 or higher for FDR correction.'};
            app.NPermEdit.Value = 2000;
            app.SaveRawCkb = uicheckbox(app.UIFigure);
            app.SaveRawCkb.Text = 'Raw';
            app.TransTypeLabel_28 = uilabel(app.UIFigure);
            app.TransTypeLabel_28.Text = 'Overlap Desc (opt)';
            app.TransTypeLabel_29 = uilabel(app.UIFigure);
            app.TransTypeLabel_29.Text = 'Overlap Desc (opt)';
            app.PPCCkb = uicheckbox(app.UIFigure);
            app.PPCCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.PPCCkb.Text = 'PPC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultTransITPCEyeGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end