classdef MultTransSpecGramGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TransTypeLabel_19   matlab.ui.control.Label
        FixSmoothCkb        matlab.ui.control.CheckBox
        PlotBox1BarCkb      matlab.ui.control.CheckBox
        PlotSepAxesBtn      matlab.ui.control.Button
        ZscoreNormCkb       matlab.ui.control.CheckBox
        AlignToEyeDD        matlab.ui.control.DropDown
        OverlapTransTypeDD  matlab.ui.control.DropDown
        TransTypeLabel_18   matlab.ui.control.Label
        OverlapDescTypeDD   matlab.ui.control.DropDown
        ShowPredListBtn     matlab.ui.control.Button
        BegEndTransCkb      matlab.ui.control.CheckBox
        PredAbsCkb          matlab.ui.control.CheckBox
        PredIdxEdit         matlab.ui.control.EditField
        CLimdBLabel_10      matlab.ui.control.Label
        AutoYlim            matlab.ui.control.CheckBox
        RmOverlapCkb        matlab.ui.control.CheckBox
        PredCkb             matlab.ui.control.CheckBox
        SaveRawCkb          matlab.ui.control.CheckBox
        PredXlim            matlab.ui.control.EditField
        PredYlim            matlab.ui.control.EditField
        CLimdBLabel_9       matlab.ui.control.Label
        IncludePredDD       matlab.ui.control.DropDown
        PvalClustEdit       matlab.ui.control.NumericEditField
        NormLowLabel_4      matlab.ui.control.Label
        DescCkb             matlab.ui.control.CheckBox
        VelCkb              matlab.ui.control.CheckBox
        WalkCkb             matlab.ui.control.CheckBox
        PatientCkb          matlab.ui.control.CheckBox
        RegionCkb           matlab.ui.control.CheckBox
        TransCkb            matlab.ui.control.CheckBox
        SelDirEdit          matlab.ui.control.EditField
        SaveDirLabel        matlab.ui.control.Label
        VelTypeDD_2         matlab.ui.control.DropDown
        TransTypeLabel_17   matlab.ui.control.Label
        VelTypeDD           matlab.ui.control.DropDown
        TransTypeLabel_16   matlab.ui.control.Label
        FullWalkNormCkb     matlab.ui.control.CheckBox
        PlotPowerCkb        matlab.ui.control.CheckBox
        PlotFooofCkb        matlab.ui.control.CheckBox
        BoxCompRng2         matlab.ui.control.EditField
        CLimdBLabel_8       matlab.ui.control.Label
        BoxCompRng1         matlab.ui.control.EditField
        CLimdBLabel_7       matlab.ui.control.Label
        TransWinEdit        matlab.ui.control.EditField
        CLimdBLabel_6       matlab.ui.control.Label
        TrialsFreqRngEdit   matlab.ui.control.EditField
        CLimdBLabel_5       matlab.ui.control.Label
        PlotTrialsCkb       matlab.ui.control.CheckBox
        SaveFigCkb          matlab.ui.control.CheckBox
        SaveBtn             matlab.ui.control.Button
        BaselineEdit        matlab.ui.control.EditField
        CLimdBLabel_3       matlab.ui.control.Label
        CLim                matlab.ui.control.EditField
        CLimdBLabel_2       matlab.ui.control.Label
        PvalEdit            matlab.ui.control.NumericEditField
        NormLowLabel_3      matlab.ui.control.Label
        DifferenceCkb       matlab.ui.control.CheckBox
        PlotButton          matlab.ui.control.Button
        PatientTypeDD_2     matlab.ui.control.DropDown
        TransTypeLabel_15   matlab.ui.control.Label
        WalkTypeDD_2        matlab.ui.control.DropDown
        TransTypeLabel_12   matlab.ui.control.Label
        RegionTypeDD_2      matlab.ui.control.DropDown
        TransTypeLabel_11   matlab.ui.control.Label
        DescTypeDD_2        matlab.ui.control.DropDown
        TransTypeLabel_10   matlab.ui.control.Label
        TransTypeDD_2       matlab.ui.control.DropDown
        TransTypeLabel_9    matlab.ui.control.Label
        PatientTypeDD       matlab.ui.control.DropDown
        TransTypeLabel_8    matlab.ui.control.Label
        CorrectionTypeDD    matlab.ui.control.DropDown
        TransTypeLabel_7    matlab.ui.control.Label
        PermTypeDD          matlab.ui.control.DropDown
        TransTypeLabel_6    matlab.ui.control.Label
        WalkTypeDD          matlab.ui.control.DropDown
        TransTypeLabel_5    matlab.ui.control.Label
        RegionTypeDD        matlab.ui.control.DropDown
        TransTypeLabel_4    matlab.ui.control.Label
        DescTypeDD          matlab.ui.control.DropDown
        TransTypeLabel_2    matlab.ui.control.Label
        TransTypeDD         matlab.ui.control.DropDown
        TransTypeLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        RWA % realworld analysis object
        fH; %specgram
        fH2; %trials plot
        fH3; %boxplot
        fH4; %velocity correlation boxplot
        fH5; %plv (not finished)
        fH6; %bar plot for box1
        fH7; %separate axes plot
        CustomRegion1;
        CustomRegion2;
        aH; %axis handle to specgram (left) or pred plot (right) showing vel/fix/kde/amb
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin>1
                app.RWA = varargin{1};
                if isempty(app.RWA.MultTrans)
                    uialert(app.UIFigure,'Run getMultTransData first!','warning');
                    return;
                end
            else
                WaitH = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Loading...');
                drawnow;
                pause(0.1);
                app.RWA = RWAnalysis2;
                app.RWA.loadMultData;
                close(WaitH);
                if isempty(app.RWA.MultTrans)
                    uialert(app.UIFigure,'RWAnalysis.mat file not found!','warning');
                    return;
                end
            end
            
            %transitions
            eventlist = unique(app.RWA.MultTrans.Evnt);
            app.TransTypeDD.Items = [{''},cellstr(eventlist)]; %app.TransTypeDD.ValueIndex = 1;
            app.TransTypeDD_2.Items = [{''},cellstr(eventlist)]; %app.TransTypeDD_2.ValueIndex = 1;

            %overlap transitions (to be removed if overlap checkbox is checked)
            app.OverlapTransTypeDD.Items = [{''},cellstr(eventlist)]; 

            %descriptions
            desclist = regexp(app.RWA.MultTrans.Desc,'\[([a-zA-Z0-9]+)\]','tokens');
            desclist(cellfun(@isempty,desclist)) = [];
            desclist = cellfun(@(x)[x{:}],desclist,'UniformOutput',false);
            desclist = unique(cat(2,desclist{:}));
            app.DescTypeDD.Items = [{''},cellstr(desclist)]; %app.DescTypeDD.ValueIndex = 1;
            app.DescTypeDD_2.Items = [{''},cellstr(desclist)]; %app.DescTypeDD_2.ValueIndex = 1;

            %overlap transition descriptions
            app.OverlapDescTypeDD.Items = [{''},cellstr(desclist)];

            %regions
            regionlist = {'AntHipp','LatTemp','Ent+Peri','PostHipp+Para','All Chans','Custom'};
            app.RegionTypeDD.Items = [{''},regionlist]; %app.RegionTypeDD.ValueIndex = 1;
            app.RegionTypeDD_2.Items = [{''},regionlist]; %app.RegionTypeDD_2.ValueIndex = 1;

            %patient
            patientlist = {'All Patients','Leave Out 1','Leave Out 2','Leave Out 3','Leave Out 4','Leave Out 5','1','2','3','4','5'};
            app.PatientTypeDD.Items = [{''},patientlist]; app.PatientTypeDD.Value = 'All Patients';
            app.PatientTypeDD_2.Items = [{''},patientlist]; app.PatientTypeDD_2.Value = 'All Patients';

            %walk
            walklist = {'First Walks','Last Walks','Stop Walks','Go Walks','All Walks','1','2','3','4','5','6','7','8'};
            app.WalkTypeDD.Items = [{''},walklist]; app.WalkTypeDD.Value = 'All Walks';
            app.WalkTypeDD_2.Items = [{''},walklist]; app.WalkTypeDD_2.Value = 'All Walks';

            %permutation
            permlist = {'standard','zscore'};
            app.PermTypeDD.Items = permlist; app.PermTypeDD.Value = 'zscore';

            %correction
            correctionlist = {'cluster','pixel','fdr','fdr+cluster'};
            app.CorrectionTypeDD.Items = [{''},correctionlist]; app.CorrectionTypeDD.Value = 'fdr';

            %velocity
            vellist = {'VelHigh','VelLow','VelHighTercile','VelMidTercile','VelLowTercile','VelHigh10','VelLow10'};
            app.VelTypeDD.Items = [{''},vellist]; app.VelTypeDD.Value = '';
            app.VelTypeDD_2.Items = [{''},vellist]; app.VelTypeDD_2.Value = '';

            %predictors
            predlist = app.RWA.PredList(2,:);
            app.IncludePredDD.Items = [{''},predlist,{'All'}];
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            transtype = app.TransTypeDD.Value;
            desctype = app.DescTypeDD.Value;
            regiontype = app.RegionTypeDD.Value;
            patienttype = app.PatientTypeDD.Value;
            walktype = app.WalkTypeDD.Value;
            veltype = app.VelTypeDD.Value;
            customregion = app.CustomRegion1;
            overlapevnts = app.OverlapTransTypeDD.Value;
            overlapdesc = app.OverlapDescTypeDD.Value;

            permtype = app.PermTypeDD.Value;
            corrtype = app.CorrectionTypeDD.Value;
            transrng = str2num(app.TransWinEdit.Value);
            normrng = str2num(app.BaselineEdit.Value);
            pval = app.PvalEdit.Value;
            pvalclust = app.PvalClustEdit.Value;
            plottrials = app.PlotTrialsCkb.Value;
            trialsfreq = str2num(app.TrialsFreqRngEdit.Value);
            plotpowercomp = app.PlotPowerCkb.Value;
            plotfooofcomp = app.PlotFooofCkb.Value;
            boxcomprng = [str2num(app.BoxCompRng1.Value);str2num(app.BoxCompRng2.Value)];
            fullwalknorm = app.FullWalkNormCkb.Value;
            predtype = app.IncludePredDD.Value;
            predidxs = str2num(app.PredIdxEdit.Value);
            predabs = app.PredAbsCkb.Value;
            rmoverlap = app.RmOverlapCkb.Value; %remove overlapping trials
            showbegendtrans = app.BegEndTransCkb.Value;
            aligntoeye = app.AlignToEyeDD.Value;
            zscore_norm = app.ZscoreNormCkb.Value;
            plotbox1bar = app.PlotBox1BarCkb.Value;
            fixsmooth = app.FixSmoothCkb.Value;
           
            b = [];
            b(1) = isempty(transtype) && ~app.TransCkb.Value;
            b(2) = isempty(regiontype) && ~app.RegionCkb.Value;
            b(3) = isempty(patienttype) && ~app.PatientCkb.Value;
            b(4) = isempty(walktype) && ~app.WalkCkb.Value;
            b(5) = isempty(permtype);
            b(6) = strcmp(regiontype,'Custom') && isempty(customregion);

            transtype2 = app.TransTypeDD_2.Value;
            desctype2 = app.DescTypeDD_2.Value;
            regiontype2 = app.RegionTypeDD_2.Value;
            patienttype2 = app.PatientTypeDD_2.Value;
            walktype2 = app.WalkTypeDD_2.Value;
            veltype2 = app.VelTypeDD_2.Value;
            customregion2 = app.CustomRegion2;
            
            b2 = [];
            b2(1) = isempty(transtype2);
            b2(2) = isempty(regiontype2);
            b2(3) = isempty(patienttype2);
            b2(4) = isempty(walktype2);
            b2(5) = strcmp(regiontype2,'Custom') && isempty(customregion2);

            %Perform some checks
            if any(b)
                uialert(app.UIFigure,'Make sure to select all options!','error');
                return;
            end

            if app.DifferenceCkb.Value
                if any(b2)
                    uialert(app.UIFigure,'Make sure to select all options on the right!','error');
                    return;
                end                
            end

            if ~all(normrng==transrng) && contains(corrtype,'cluster')
                uialert(app.UIFigure,'Transition and baseline windows must match for cluster correction!','error');
                return;
            end

            %Plot
            if app.DifferenceCkb.Value
                pb = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Generating plot...'); pb.Value = 1;
                app.fH = app.RWA.plotMultTransSpecDiffPerm('transtype',{transtype,transtype2},'regiontype',{regiontype,regiontype2},...
                    'walktype',{walktype,walktype2},'permtype',permtype,'correctiontype',corrtype,'pval',pval,'clim',str2num(app.CLim.Value),...
                    'desctype',{desctype,desctype2},'patienttype',{patienttype,patienttype2},'transrng',transrng,'fullwalknorm',fullwalknorm,...
                    'veltype',{veltype,veltype2},'customregion',{customregion,customregion2},'predtype',predtype,'pvalclust',pvalclust,...
                    'predabs',predabs,'fixsmooth',fixsmooth,'overlapevnts',overlapevnts,'overlapdesc',overlapdesc,'rmoverlap',rmoverlap);
                app.aH = findobj(app.fH,'Type','axes');
                app.PredXlim.Value = num2str(get(app.aH,'xlim'),'%0.1f,%0.1f');
                if ~isempty(predtype)
                    yyaxis(app.aH,'right')
                    app.PredYlim.Value = num2str(get(app.aH,'ylim'),'%0.1f,%0.1f');
                end
                close(pb);
                drawnow; pause(0.1);

            else
                if app.TransCkb.Value
                    transcell = app.TransTypeDD.Items(2:end);
                else
                    transcell = {transtype};
                end
                if app.RegionCkb.Value
                    regioncell = app.RegionTypeDD.Items(2:end-1);
                else
                    regioncell = {regiontype};
                end
                if app.PatientCkb.Value
                    patientcell = app.PatientTypeDD.Items(2:end);
                else
                    patientcell = {patienttype};
                end
                if app.WalkCkb.Value
                    walkcell = app.WalkTypeDD.Items(2:end);
                else
                    walkcell = {walktype};
                end
                if app.VelCkb.Value
                    velcell = app.VelTypeDD.Items(2:end);
                else
                    velcell = {veltype};
                end
                if app.PredCkb.Value
                    predcell = app.IncludePredDD.Items(1:end);
                else
                    predcell = {predtype};
                end

                ntrans = length(transcell);
                nregion = length(regioncell);
                npatient = length(patientcell);
                nwalk = length(walkcell);
                nvel = length(velcell);
                npred = length(predcell);
                ntotal = ntrans*nregion*npatient*nwalk*nvel*npred;

                [tidx,ridx,pidx,widx,vidx,pridx] = ndgrid(1:ntrans,1:nregion,1:npatient,1:nwalk,1:nvel,1:npred);
                idx = [tidx(:),ridx(:),pidx(:),widx(:),vidx(:),pridx(:)];

                if size(idx,1)~=ntotal
                    uialert(app.UIFigure,'Iteration size mismatch!','error');
                    return;
                end

                iterflag = any([ntrans,nregion,npatient,nwalk,nvel,npred,ntotal]>1);
                if iterflag && (app.PlotPowerCkb.Value || app.PlotTrialsCkb.Value || app.SaveFigCkb.Value || app.SaveRawCkb.Value)
                    uialert(app.UIFigure,'Uncheck "Plot Trials", "Plot Comp", "Raw", or "Fig"','error');
                    return;
                end

                if iterflag
                    sel = uiconfirm(app.UIFigure,sprintf('%d iterations will be performed and saved. Continue?',ntotal),'Continue?');
                    switch sel
                        case 'Cancel'
                            return;
                    end
                end

                pb = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Generating plot...');
                for k=1:ntotal
                    pb.Value = (k/ntotal);
                    skipflag = false;
                    if k>1
                        if all(idx(k-1,1:5)==idx(k,1:5))
                            skipflag = true;
                        end
                    end
                    if skipflag
                        app.IncludePredDD.ValueIndex = idx(k,6);
                        app.IncludePredDDFcn;
                        if any(ishandle(app.fH4)); close(app.fH4); end %no need to replot fooof for all predictors 
                    else
                        if ntotal>1
                            if any(ishandle(app.fH)); close(app.fH); end
                            if any(ishandle(app.fH2)); close(app.fH2); end
                            if any(ishandle(app.fH3)); close(app.fH3); end
                            if any(ishandle(app.fH4)); close(app.fH4); end
                        end
                        [app.fH,app.fH2,app.fH3,app.fH4,app.fH5,app.fH6] = app.RWA.plotMultTransSpecGramPerm('transtype',transcell{idx(k,1)},'regiontype',regioncell{idx(k,2)},...
                            'walktype',walkcell{idx(k,4)},'permtype',permtype,'correctiontype',corrtype,'clim',str2num(app.CLim.Value),...
                            'desctype',desctype,'patienttype',patientcell{idx(k,3)},'pval',pval,'pvalclust',pvalclust,'transrng',transrng,...
                            'plottrials',plottrials,'trialsfreqrng',trialsfreq,'normrng',normrng,'plotpowercomp',plotpowercomp,...
                            'plotfooofcomp',plotfooofcomp,'boxcomprng',boxcomprng,'fullwalknorm',fullwalknorm,'veltype',velcell{idx(k,5)},...
                            'customregion',customregion,'predtype',predcell{idx(k,6)},'rmoverlap',rmoverlap,'predidxs',predidxs,'predabs',predabs,...
                            'showbegendtrans',showbegendtrans,'overlapevnts',overlapevnts,'aligntoeye',aligntoeye,'zscore_norm',zscore_norm,...
                            'plotbox1bar',plotbox1bar,'fixsmooth',fixsmooth,'overlapdesc',overlapdesc);
                    end
                    app.aH = app.fH.UserData.aH;
                    app.PredXlim.Value = num2str(get(app.aH,'xlim'),'%0.1f,%0.1f');
                    if ~isempty(predcell{idx(k,6)})
                        yyaxis(app.aH,'right')
                        app.PredYlim.Value = num2str(get(app.aH,'ylim'),'%0.1f,%0.1f');
                    end
                    drawnow; pause(0.1);
                    if iterflag
                        app.SaveBtnFcn;
                    end
                end
                close(pb);

            end

        end

        % Value changed function: DifferenceCkb
        function DifferenceCkbFcn(app, event)
            if app.DifferenceCkb.Value
                app.PermTypeFcn;
                app.BaselineEdit.Enable = 'off';
                app.TransTypeDD_2.Enable = 'on';
                app.DescTypeDD_2.Enable = 'on';
                app.RegionTypeDD_2.Enable = 'on';
                app.PatientTypeDD_2.Enable = 'on';
                app.WalkTypeDD_2.Enable = 'on';
                app.VelTypeDD_2.Enable = 'on';
                app.PlotTrialsCkb.Value = false;
                app.PlotTrialsCkb.Enable = 'off';
            else
                app.PermTypeFcn;
                app.BaselineEdit.Enable = 'on';
                app.TransTypeDD_2.Enable = 'off';
                app.DescTypeDD_2.Enable = 'off';
                app.RegionTypeDD_2.Enable = 'off';
                app.PatientTypeDD_2.Enable = 'off';
                app.WalkTypeDD_2.Enable = 'off';
                app.VelTypeDD_2.Enable = 'off';
                app.PlotTrialsCkb.Enable = 'on';
            end
            
        end

        % Value changed function: FullWalkNormCkb
        function FullWalkNormCkbFcn(app, event)
            if app.FullWalkNormCkb.Value
                app.BaselineEdit.Enable = 'off';
            else
                app.BaselineEdit.Enable = 'on';
            end
            
        end

        % Button pushed function: SaveBtn
        function SaveBtnFcn(app, event)
            if any(ishandle(app.fH))
                app.SaveBtn.BackgroundColor = 'r';
                if isempty(app.SelDirEdit.Value)
                    app.SelDirEdit.Value = lastDir('Choose the destination folder...');
                end
                if ~isfolder(app.SelDirEdit.Value)
                    mkdir(app.SelDirEdit.Value);
                end
                tH = get(app.aH(end),'title');
                tStr = tH.String;
                tStr = regexprep(tStr,'\s+','');
                tStr = regexprep(tStr,'\\|/','-');
                tStr = regexprep(tStr,'<','');
                tStr = regexprep(tStr,'::','~');
                tStr = regexprep(tStr,'\|','+');
                tStr = regexprep(tStr,'\(',',');
                tStr = regexprep(tStr,'\)','');
                tStr = regexprep(tStr,'=','');
                tStr = cell2mat(reshape(tStr,1,[]));
                if app.aH.YAxis(2).Visible 
                    yyaxis(app.aH,'right'); 
                    pred_str = get(app.aH,'ylabel');
                    if isempty(pred_str.String)
                        tStr = [tStr,',All'];
                    else
                        tStr = [tStr,',',pred_str.String];
                    end
                end
                fstr = sprintf('MTGUI_%s.png',tStr);
                print(app.fH,fullfile(app.SelDirEdit.Value,fstr),'-dpng','-r300');
                if app.SaveFigCkb.Value
                    savefig(app.fH,fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','fig')))
                end
                if app.SaveRawCkb.Value
                    MT = app.RWA.MultTrans.MT;
                    MTd = app.RWA.MultTrans.MTd;
                    save(fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','mat')),"MT","MTd",'-v7.3')
                end
                if any(ishandle(app.fH2))
                    fstr2 = regexprep(fstr,'\.png$',['_Trials',regexprep(app.TrialsFreqRngEdit.Value,',','t'),'.png']);
                    print(app.fH2,fullfile(app.SelDirEdit.Value,fstr2),'-dpng','-r300');
                    if app.SaveFigCkb.Value
                        savefig(app.fH2,fullfile(app.SelDirEdit.Value,regexprep(fstr2,'png$','fig')))
                    end
                end
                if any(ishandle(app.fH3))
                    fstr3 = regexprep(fstr,'\.png$',['_BoxPower',regexprep(app.BoxCompRng1.Value,',','t'),',',regexprep(app.BoxCompRng2.Value,',','t'),'.png']);
                    print(app.fH3,fullfile(app.SelDirEdit.Value,fstr3),'-dpng','-r300');
                    if app.SaveFigCkb.Value
                        savefig(app.fH3,fullfile(app.SelDirEdit.Value,regexprep(fstr3,'png$','fig')))
                    end
                end
                if any(ishandle(app.fH4))
                    fstr4 = regexprep(fstr,'\.png$',['_BoxFooof',regexprep(app.BoxCompRng1.Value,',','t'),',',regexprep(app.BoxCompRng2.Value,',','t'),'.png']);
                    print(app.fH4,fullfile(app.SelDirEdit.Value,fstr4),'-dpng','-r300');
                    if app.SaveFigCkb.Value
                        savefig(app.fH4,fullfile(app.SelDirEdit.Value,regexprep(fstr4,'png$','fig')))
                    end
                end
                if any(ishandle(app.fH6))
                    fstr6 = regexprep(fstr,'\.png$',['_BoxPowerBar',regexprep(app.BoxCompRng1.Value,',','t'),'.png']);
                    print(app.fH6,fullfile(app.SelDirEdit.Value,fstr6),'-dpng','-r300');
                    if app.SaveFigCkb.Value
                        savefig(app.fH6,fullfile(app.SelDirEdit.Value,regexprep(fstr6,'png$','fig')))
                    end
                end
                if any(ishandle(app.fH7))
                    fstr7 = regexprep(fstr,'\.png$','_SepAxes.png');
                    print(app.fH7,fullfile(app.SelDirEdit.Value,fstr7),'-dpng','-r300');
                    if app.SaveFigCkb.Value
                        savefig(app.fH7,fullfile(app.SelDirEdit.Value,regexprep(fstr7,'png$','fig')))
                    end
                end
                app.SaveBtn.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: PermTypeDD
        function PermTypeFcn(app, event)
            switch app.PermTypeDD.Value
                case 'standard'
                    if app.DifferenceCkb.Value
                        app.CLim.Value = '-10,10';
                    else
                        app.CLim.Value = '-1,1';
                    end
                case 'zscore'
                    app.CLim.Value = '-10,10';
            end
        end

        % Value changed function: CorrectionTypeDD
        function CorrectionTypeFcn(app, event)
            switch app.CorrectionTypeDD.Value
                case 'fdr+cluster'
                    app.PermTypeDD.Value = 'zscore';
                    app.PermTypeDD.Enable = 'off';
                otherwise
                    app.PermTypeDD.Enable = 'on';
            end
        end

        % Value changed function: RegionTypeDD
        function RegionTypeFcn(app, event)
            if strcmp(app.RegionTypeDD.Value,'Custom')
                CustomRegionGUI(app,1);
            end            
        end

        % Value changed function: RegionTypeDD_2
        function RegionTypeFcn2(app, event)
            if strcmp(app.RegionTypeDD_2.Value,'Custom')
                CustomRegionGUI(app,2);
            end     
        end

        % Value changed function: PredXlim
        function PredXlimFcn(app, event)
            if ~isempty(app.PredXlim.Value)
                set(app.aH,'xlim',str2num(app.PredXlim.Value))
            end
        end

        % Value changed function: PredYlim
        function PredYlimFcn(app, event)
            if ~isempty(app.PredYlim.Value)
                yyaxis(app.aH,'right');
                set(app.aH,'ylim',str2num(app.PredYlim.Value))
            end
        end

        % Value changed function: CLim
        function CLimFcn(app, event)
            if any(str2num(app.CLim.Value)==0)
                yyaxis(app.aH,'left');
                set(findobj(app.aH.Children,'Type','Contour'),'Visible','off');
                set(app.aH.YAxis(1),'Visible','off');
                set(findobj(app.fH,'Type','Colorbar'),'Visible','off');
            else
                yyaxis(app.aH,'left');
                set(findobj(app.aH.Children,'Type','Contour'),'Visible','on');
                set(app.aH.YAxis(1),'Visible','on');
                set(app.aH,'clim',str2num(app.CLim.Value),'Visible','on')
                set(findobj(app.fH,'Type','Colorbar'),'Visible','on');
            end
            if isempty(app.IncludePredDD.Value)
                set(app.aH.YAxis(2),'Visible','off');
            end
        end

        % Value changed function: IncludePredDD, PredIdxEdit
        function IncludePredDDFcn(app, event)
            if any(ishandle(app.aH))
                yyaxis(app.aH,'right');
                pH = app.fH.UserData.pH;
                predlist = app.RWA.PredList;
                predidxs = str2num(app.PredIdxEdit.Value);
                predtype = app.IncludePredDD.Value;
                predytick = -(0:size(app.RWA.PredList,2)-1)*app.RWA.PredYscale;
                set(pH,'visible','off')
                if ~isempty(predtype)
                    if contains(predtype,'All')
                        if isempty(predidxs)
                            predidxs = 1:size(predlist,2);
                        end
                        pytick = predytick(1:length(predidxs));
                        plist = app.RWA.PredList(2,predidxs);
                        for k=1:length(predidxs)
                            idx = predidxs(k);
                            ph = pH(idx,4);
                            y = get(ph,'YData');
                            y = y-mean(y);
                            y = y+pytick(k);
                            set(ph,'visible','on','ydata',y);
                        end
                        set(app.aH,'YTick',fliplr(pytick),'YTickLabel',fliplr(plist))
                        ylim(app.aH,[pytick(end)-app.RWA.PredYscale,app.RWA.PredYscale])
                        ylabel(app.aH,'');
                    else
                        predidx = strcmp(predlist(2,:),predtype);
                        set(pH(predidx,1:3),'visible','on');
                        set(app.aH,'YTickMode','auto','YTickLabelMode','auto')
                        if app.AutoYlim.Value
                            ylim(app.aH,'auto')
                        else
                            if app.DifferenceCkb.Value
                                yl = diff(app.RWA.PredYlim(predidx,:));
                                ylim(app.aH,[-yl,yl])
                            else
                                if contains(predtype,'HeadTurn') && ~app.PredAbsCkb.Value
                                    yl = app.RWA.PredYlim(predidx,:);
                                    ylim(app.aH,[-yl(2),yl(2)])
                                else
                                    ylim(app.aH,app.RWA.PredYlim(predidx,:))
                                end
                            end
                        end
                        ylabel(app.aH,predlist{2,predidx})
                    end
                    set(app.aH.YAxis(2),'Visible','on');
                    app.PredYlim.Value = num2str(get(app.aH,'ylim'),'%0.1f,%0.1f');
                else
                    set(app.aH.YAxis(2),'Visible','off');
                end
            end
        end

        % Value changed function: AutoYlim
        function AutoYlimFcn(app, event)
            if any(ishandle(app.aH))
                if ~strcmp(app.IncludePredDD.Value,'All')
                    yyaxis(app.aH,'right');
                    if app.AutoYlim.Value
                        ylim(app.aH,'auto')
                    else
                        predlist = app.RWA.PredList;
                        predtype = app.IncludePredDD.Value;
                        predidx = strcmp(predlist(2,:),predtype);
                        if app.DifferenceCkb.Value
                            yl = diff(app.RWA.PredYlim(predidx,:));
                            ylim(app.aH,[-yl,yl])
                        else
                            if contains(predtype,'HeadTurn') && ~app.PredAbsCkb.Value
                                yl = app.RWA.PredYlim(predidx,:);
                                ylim(app.aH,[-yl(2),yl(2)])
                            else
                                ylim(app.aH,app.RWA.PredYlim(predidx,:))
                            end
                        end
                    end
                    app.PredYlim.Value = num2str(get(app.aH,'ylim'),'%0.1f,%0.1f');
                end
            end
        end

        % Value changed function: BegEndTransCkb
        function BegEndTransFcn(app, event)
            if any(ishandle(app.fH))
                if isfield(app.fH.UserData,'evH')
                    if app.BegEndTransCkb.Value
                        set(app.fH.UserData.evH,'Visible','on');
                    else
                        set(app.fH.UserData.evH,'Visible','off');
                    end
                end
            end
        end

        % Button pushed function: ShowPredListBtn
        function ShowPredListBtnPushed(app, event)
            msgbox(cellfun(@(x,y)[num2str(x),' = ',y],num2cell(1:size(app.RWA.PredList,2)),app.RWA.PredList(2,:),'UniformOutput',false)');
        end

        % Button pushed function: PlotSepAxesBtn
        function PlotSepAxesBtnButtonPushed(app, event)
            if any(ishandle(app.aH))
                pH = app.fH.UserData.pH;
                predlist = app.RWA.PredList;
                predidxs = str2num(app.PredIdxEdit.Value);
                predtype = app.IncludePredDD.Value;
                if ~isempty(predtype)
                    if contains(predtype,'All')
                        if isempty(predidxs)
                            predidxs = 1:size(predlist,2);
                        end
                        plist = app.RWA.PredList(2,predidxs);
                        app.fH7 = figure('position',[50,50,1000,1000]);
                        tl = tiledlayout(length(plist),1,'Parent',app.fH7,'TileSpacing','compact','Padding','compact');
                        for k=1:length(predidxs)
                            ah = nexttile(tl,k);
                            idx = predidxs(k);
                            ph = pH(idx,1); %1=actual, 2=lower, 3=upper, 4=zscore
                            ph_low = pH(idx,2);
                            ph_high = pH(idx,3);
                            x = get(ph,'XData');
                            y = get(ph,'YData');
                            y_low = get(ph_low,'YData');
                            y_high = get(ph_high,'YData');
                            plot(ah,x,y,'k');
                            hold(ah,'on');
                            plot(ah,x,y_low,':k');
                            plot(ah,x,y_high,':k');
                            if any(strcmp(predlist{1,predidxs(k)},{'th','al','be','ga','hg'}))
                                plot(ah,[x(1),x(end)],[0,0],'--k');
                            end
                            ylimit = get(ah,'ylim');
                            plot(ah,[0,0],ylimit,'k','LineWidth',1);
                            if app.BegEndTransCkb.Value
                                np_m = app.fH.UserData.np_m;
                                plot(ah,[np_m(2),np_m(2)],ylimit,'k',[np_m(1),np_m(1)],ylimit,':k',[np_m(3),np_m(3)],ylimit,':k');
                            end
                            ylim(ah,ylimit);
                            title(ah,plist{k});
                            ylabel(ah,predlist{3,predidxs(k)})
                            if k==length(predidxs)
                                xlabel(ah,'sec');
                            else
                                set(ah,'xticklabel',[]);
                            end
                        end
                    end
                end
            end %end if
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            disp('closing...')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TransTypeLabel = uilabel(app.UIFigure);
            app.TransTypeLabel.Text = 'Transition';
            app.TransTypeDD = uidropdown(app.UIFigure);
            app.TransTypeDD.Items = {''};
            app.TransTypeDD.Editable = 'on';
            app.TransTypeDD.Tooltip = {'| symbol can be added between transitions to include more than one'};
            app.TransTypeDD.Value = '';
            app.TransTypeLabel_2 = uilabel(app.UIFigure);
            app.TransTypeLabel_2.Text = 'Description (opt)';
            app.DescTypeDD = uidropdown(app.UIFigure);
            app.DescTypeDD.Items = {''};
            app.DescTypeDD.Editable = 'on';
            app.DescTypeDD.Tooltip = {'| symbol can be added between each description to include more than one'};
            app.DescTypeDD.Value = '';
            app.TransTypeLabel_4 = uilabel(app.UIFigure);
            app.TransTypeLabel_4.Text = 'Region';
            app.RegionTypeDD = uidropdown(app.UIFigure);
            app.RegionTypeDD.Items = {''};
            app.RegionTypeDD.Editable = 'on';
            app.RegionTypeDD.ValueChangedFcn = createCallbackFcn(app, @RegionTypeFcn, true);
            app.RegionTypeDD.Tooltip = {'| symbol can be added between region names (except custom) to include more than one'};
            app.RegionTypeDD.Value = '';
            app.TransTypeLabel_5 = uilabel(app.UIFigure);
            app.TransTypeLabel_5.Text = 'Walk';
            app.WalkTypeDD = uidropdown(app.UIFigure);
            app.WalkTypeDD.Items = {''};
            app.WalkTypeDD.Editable = 'on';
            app.WalkTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.WalkTypeDD.Value = '';
            app.TransTypeLabel_6 = uilabel(app.UIFigure);
            app.TransTypeLabel_6.Text = 'Permutation';
            app.PermTypeDD = uidropdown(app.UIFigure);
            app.PermTypeDD.Items = {''};
            app.PermTypeDD.ValueChangedFcn = createCallbackFcn(app, @PermTypeFcn, true);
            app.PermTypeDD.Value = '';
            app.TransTypeLabel_7 = uilabel(app.UIFigure);
            app.TransTypeLabel_7.Text = 'Correction';
            app.CorrectionTypeDD = uidropdown(app.UIFigure);
            app.CorrectionTypeDD.Items = {''};
            app.CorrectionTypeDD.ValueChangedFcn = createCallbackFcn(app, @CorrectionTypeFcn, true);
            app.CorrectionTypeDD.Value = '';
            app.TransTypeLabel_8 = uilabel(app.UIFigure);
            app.TransTypeLabel_8.Text = 'Patient';
            app.PatientTypeDD = uidropdown(app.UIFigure);
            app.PatientTypeDD.Items = {''};
            app.PatientTypeDD.Editable = 'on';
            app.PatientTypeDD.Tooltip = {'A list of patient numbers can be added manually (i.e. 1,3,4)'};
            app.PatientTypeDD.Value = '';
            app.TransTypeLabel_9 = uilabel(app.UIFigure);
            app.TransTypeLabel_9.Text = 'Transition';
            app.TransTypeDD_2 = uidropdown(app.UIFigure);
            app.TransTypeDD_2.Items = {''};
            app.TransTypeDD_2.Editable = 'on';
            app.TransTypeDD_2.Enable = 'off';
            app.TransTypeDD_2.Value = '';
            app.TransTypeLabel_10 = uilabel(app.UIFigure);
            app.TransTypeLabel_10.Text = 'Description (opt)';
            app.DescTypeDD_2 = uidropdown(app.UIFigure);
            app.DescTypeDD_2.Items = {''};
            app.DescTypeDD_2.Editable = 'on';
            app.DescTypeDD_2.Enable = 'off';
            app.DescTypeDD_2.Value = '';
            app.TransTypeLabel_11 = uilabel(app.UIFigure);
            app.TransTypeLabel_11.Text = 'Region';
            app.RegionTypeDD_2 = uidropdown(app.UIFigure);
            app.RegionTypeDD_2.Items = {''};
            app.RegionTypeDD_2.Editable = 'on';
            app.RegionTypeDD_2.ValueChangedFcn = createCallbackFcn(app, @RegionTypeFcn2, true);
            app.RegionTypeDD_2.Enable = 'off';
            app.RegionTypeDD_2.Value = '';
            app.TransTypeLabel_12 = uilabel(app.UIFigure);
            app.TransTypeLabel_12.Text = 'Walk';
            app.WalkTypeDD_2 = uidropdown(app.UIFigure);
            app.WalkTypeDD_2.Items = {''};
            app.WalkTypeDD_2.Editable = 'on';
            app.WalkTypeDD_2.Enable = 'off';
            app.WalkTypeDD_2.Value = '';
            app.TransTypeLabel_15 = uilabel(app.UIFigure);
            app.TransTypeLabel_15.Text = 'Patient';
            app.PatientTypeDD_2 = uidropdown(app.UIFigure);
            app.PatientTypeDD_2.Items = {''};
            app.PatientTypeDD_2.Editable = 'on';
            app.PatientTypeDD_2.Enable = 'off';
            app.PatientTypeDD_2.Value = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DifferenceCkb = uicheckbox(app.UIFigure);
            app.DifferenceCkb.ValueChangedFcn = createCallbackFcn(app, @DifferenceCkbFcn, true);
            app.DifferenceCkb.Text = 'Difference';
            app.NormLowLabel_3 = uilabel(app.UIFigure);
            app.NormLowLabel_3.Text = 'Pval';
            app.PvalEdit = uieditfield(app.UIFigure, 'numeric');
            app.PvalEdit.Limits = [0 1];
            app.PvalEdit.Value = 0.05;
            app.CLimdBLabel_2 = uilabel(app.UIFigure);
            app.CLimdBLabel_2.Text = 'CLim';
            app.CLim = uieditfield(app.UIFigure, 'text');
            app.CLim.ValueChangedFcn = createCallbackFcn(app, @CLimFcn, true);
            app.CLim.Tooltip = {'Color limits in units of zscore for plotting specgram (enter zero to hide specgram)'};
            app.CLim.Value = '-10,10';
            app.CLimdBLabel_3 = uilabel(app.UIFigure);
            app.CLimdBLabel_3.Text = 'Baseline';
            app.BaselineEdit = uieditfield(app.UIFigure, 'text');
            app.BaselineEdit.Tooltip = {'Baseline range in sec. Must be contained within transition window.'};
            app.BaselineEdit.Value = '-10,10';
            app.SaveBtn = uibutton(app.UIFigure, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnFcn, true);
            app.SaveBtn.Text = 'Save';
            app.SaveFigCkb = uicheckbox(app.UIFigure);
            app.SaveFigCkb.Text = 'Fig';
            app.PlotTrialsCkb = uicheckbox(app.UIFigure);
            app.PlotTrialsCkb.Tooltip = {'Plots individual trials for specified frequency range and groups by patient'};
            app.PlotTrialsCkb.Text = 'Plot Trials';
            app.CLimdBLabel_5 = uilabel(app.UIFigure);
            app.CLimdBLabel_5.Text = 'Freq (Hz)';
            app.TrialsFreqRngEdit = uieditfield(app.UIFigure, 'text');
            app.TrialsFreqRngEdit.Tooltip = {'Frequency range for plotting individual trials'};
            app.TrialsFreqRngEdit.Value = '5,8';
            app.CLimdBLabel_6 = uilabel(app.UIFigure);
            app.CLimdBLabel_6.Text = 'Window';
            app.TransWinEdit = uieditfield(app.UIFigure, 'text');
            app.TransWinEdit.Tooltip = {'Transition window range in sec'};
            app.TransWinEdit.Value = '-10,10';
            app.CLimdBLabel_7 = uilabel(app.UIFigure);
            app.CLimdBLabel_7.Text = 'Box1';
            app.BoxCompRng1 = uieditfield(app.UIFigure, 'text');
            app.BoxCompRng1.Tooltip = {'Location of box1 with time/freq ranges in sec/Hz (t1,t2,f1,f2)'};
            app.BoxCompRng1.Value = '-4,0,5,8';
            app.CLimdBLabel_8 = uilabel(app.UIFigure);
            app.CLimdBLabel_8.Text = 'Box2';
            app.BoxCompRng2 = uieditfield(app.UIFigure, 'text');
            app.BoxCompRng2.Tooltip = {'Location of box2 with time/freq ranges in sec/Hz (t1,t2,f1,f2)'};
            app.BoxCompRng2.Value = '0,4,5,8';
            app.PlotFooofCkb = uicheckbox(app.UIFigure);
            app.PlotFooofCkb.Tooltip = {'Boxplot comparison for specified box locations with averaging across patient and region'};
            app.PlotFooofCkb.Text = 'Plot Fooof';
            app.PlotPowerCkb = uicheckbox(app.UIFigure);
            app.PlotPowerCkb.Tooltip = {'Boxplot comparison for specified box locations with averaging across patient and region'};
            app.PlotPowerCkb.Text = 'Plot Power';
            app.FullWalkNormCkb = uicheckbox(app.UIFigure);
            app.FullWalkNormCkb.ValueChangedFcn = createCallbackFcn(app, @FullWalkNormCkbFcn, true);
            app.FullWalkNormCkb.Tooltip = {'Full walk normlization instead of across transition window. If selected, baseline values are skipped.'};
            app.FullWalkNormCkb.Text = 'Full Walk';
            app.FullWalkNormCkb.Value = true;
            app.TransTypeLabel_16 = uilabel(app.UIFigure);
            app.TransTypeLabel_16.Text = 'Velocity';
            app.VelTypeDD = uidropdown(app.UIFigure);
            app.VelTypeDD.Items = {''};
            app.VelTypeDD.Editable = 'on';
            app.VelTypeDD.Tooltip = {'These represent the amount of velocity change in +-5sec window centered on transition'};
            app.VelTypeDD.Value = '';
            app.TransTypeLabel_17 = uilabel(app.UIFigure);
            app.TransTypeLabel_17.Text = 'Velocity';
            app.VelTypeDD_2 = uidropdown(app.UIFigure);
            app.VelTypeDD_2.Items = {''};
            app.VelTypeDD_2.Editable = 'on';
            app.VelTypeDD_2.Enable = 'off';
            app.VelTypeDD_2.Value = '';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'Save Dir';
            app.SelDirEdit = uieditfield(app.UIFigure, 'text');
            app.TransCkb = uicheckbox(app.UIFigure);
            app.TransCkb.Tooltip = {'Iterate and save'};
            app.TransCkb.Text = '';
            app.RegionCkb = uicheckbox(app.UIFigure);
            app.RegionCkb.Tooltip = {'Iterate and save'};
            app.RegionCkb.Text = '';
            app.PatientCkb = uicheckbox(app.UIFigure);
            app.PatientCkb.Tooltip = {'Iterate and save'};
            app.PatientCkb.Text = '';
            app.WalkCkb = uicheckbox(app.UIFigure);
            app.WalkCkb.Tooltip = {'Iterate and save'};
            app.WalkCkb.Text = '';
            app.VelCkb = uicheckbox(app.UIFigure);
            app.VelCkb.Tooltip = {'Iterate and save'};
            app.VelCkb.Text = '';
            app.DescCkb = uicheckbox(app.UIFigure);
            app.DescCkb.Enable = 'off';
            app.DescCkb.Text = '';
            app.NormLowLabel_4 = uilabel(app.UIFigure);
            app.NormLowLabel_4.Text = 'PvalClust';
            app.PvalClustEdit = uieditfield(app.UIFigure, 'numeric');
            app.PvalClustEdit.Limits = [0 1];
            app.PvalClustEdit.Value = 0.01;
            app.IncludePredDD = uidropdown(app.UIFigure);
            app.IncludePredDD.Items = {};
            app.IncludePredDD.ValueChangedFcn = createCallbackFcn(app, @IncludePredDDFcn, true);
            app.IncludePredDD.Tooltip = {'Overlay predictor data. Use Ylim to adjust the y-axis limits or Auto Ckb.'};
            app.IncludePredDD.Value = {};
            app.CLimdBLabel_9 = uilabel(app.UIFigure);
            app.CLimdBLabel_9.Text = 'Ylim';
            app.PredYlim = uieditfield(app.UIFigure, 'text');
            app.PredYlim.ValueChangedFcn = createCallbackFcn(app, @PredYlimFcn, true);
            app.PredYlim.Tooltip = {'Adjust y-axis limits for overlaid predictor'};
            app.PredYlim.Value = '0,1';
            app.PredXlim = uieditfield(app.UIFigure, 'text');
            app.PredXlim.ValueChangedFcn = createCallbackFcn(app, @PredXlimFcn, true);
            app.PredXlim.Tooltip = {'Adjust y-axis limits for overlaid predictor'};
            app.PredXlim.Value = '0,1';
            app.SaveRawCkb = uicheckbox(app.UIFigure);
            app.SaveRawCkb.Text = 'Raw';
            app.PredCkb = uicheckbox(app.UIFigure);
            app.PredCkb.Tooltip = {'Iterate and save'};
            app.PredCkb.Text = '';
            app.RmOverlapCkb = uicheckbox(app.UIFigure);
            app.RmOverlapCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.RmOverlapCkb.Text = 'No Overlap';
            app.RmOverlapCkb.Value = true;
            app.AutoYlim = uicheckbox(app.UIFigure);
            app.AutoYlim.ValueChangedFcn = createCallbackFcn(app, @AutoYlimFcn, true);
            app.AutoYlim.Text = 'Auto';
            app.CLimdBLabel_10 = uilabel(app.UIFigure);
            app.CLimdBLabel_10.Text = 'Xlim';
            app.PredIdxEdit = uieditfield(app.UIFigure, 'text');
            app.PredIdxEdit.ValueChangedFcn = createCallbackFcn(app, @IncludePredDDFcn, true);
            app.PredIdxEdit.Tooltip = {'comma separated predictor indices for specgram overlay when "All" is selected from dropdown'};
            app.PredAbsCkb = uicheckbox(app.UIFigure);
            app.PredAbsCkb.Tooltip = {'Applies abs to head turn data. Only works for initial plot (not interactive)'};
            app.PredAbsCkb.Text = 'Abs';
            app.PredAbsCkb.Value = true;
            app.BegEndTransCkb = uicheckbox(app.UIFigure);
            app.BegEndTransCkb.ValueChangedFcn = createCallbackFcn(app, @BegEndTransFcn, true);
            app.BegEndTransCkb.Tooltip = {'Boxplot comparison for specified box locations with averaging across patient and region'};
            app.BegEndTransCkb.Text = 'Show Beg/End';
            app.ShowPredListBtn = uibutton(app.UIFigure, 'push');
            app.ShowPredListBtn.ButtonPushedFcn = createCallbackFcn(app, @ShowPredListBtnPushed, true);
            app.ShowPredListBtn.Tooltip = {'Show predictor list'};
            app.ShowPredListBtn.Text = '...';
            app.OverlapDescTypeDD = uidropdown(app.UIFigure);
            app.OverlapDescTypeDD.Items = {''};
            app.OverlapDescTypeDD.Editable = 'on';
            app.OverlapDescTypeDD.Tooltip = {'| symbol can be added between each description to include more than one'};
            app.OverlapDescTypeDD.Value = '';
            app.TransTypeLabel_18 = uilabel(app.UIFigure);
            app.TransTypeLabel_18.Text = 'Overlap Transitions';
            app.OverlapTransTypeDD = uidropdown(app.UIFigure);
            app.OverlapTransTypeDD.Items = {''};
            app.OverlapTransTypeDD.Editable = 'on';
            app.OverlapTransTypeDD.Tooltip = {'Remove any trials where the overlap transition type overlaps with the main transition type.'};
            app.OverlapTransTypeDD.Value = '';
            app.AlignToEyeDD = uidropdown(app.UIFigure);
            app.AlignToEyeDD.Items = {'', 'saccade', 'fixation'};
            app.AlignToEyeDD.Value = '';
            app.ZscoreNormCkb = uicheckbox(app.UIFigure);
            app.ZscoreNormCkb.Tooltip = {'Boxplot comparison for specified box locations with averaging across patient and region'};
            app.ZscoreNormCkb.Text = 'Zscore Norm';
            app.PlotSepAxesBtn = uibutton(app.UIFigure, 'push');
            app.PlotSepAxesBtn.ButtonPushedFcn = createCallbackFcn(app, @PlotSepAxesBtnButtonPushed, true);
            app.PlotSepAxesBtn.Text = 'Plot Separate Axes';
            app.PlotBox1BarCkb = uicheckbox(app.UIFigure);
            app.PlotBox1BarCkb.Tooltip = {'Barplot showing power and se for box1 with averaging across patient and region'};
            app.PlotBox1BarCkb.Text = 'Plot Bar';
            app.FixSmoothCkb = uicheckbox(app.UIFigure);
            app.FixSmoothCkb.Tooltip = {'Applies smoothing over time to fixations. Only works for initial plot (not interactive)'};
            app.FixSmoothCkb.Text = 'Smt';
            app.FixSmoothCkb.Value = true;
            app.TransTypeLabel_19 = uilabel(app.UIFigure);
            app.TransTypeLabel_19.Text = 'Description (opt)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultTransSpecGramGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end