classdef RWNApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SyncAudCkb                      matlab.ui.control.CheckBox
        XSOffsetEdit                    matlab.ui.control.NumericEditField
        XSOffsetsecLabel                matlab.ui.control.Label
        PlayButton                      matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.StateButton
        FrameSkipEdit                   matlab.ui.control.NumericEditField
        SkipframesLabel                 matlab.ui.control.Label
        GoToEditField                   matlab.ui.control.EditField
        GoTohhmmssmsEditFieldLabel      matlab.ui.control.Label
        GoProOffsetEdit                 matlab.ui.control.NumericEditField
        GoProOffsetsecEditFieldLabel    matlab.ui.control.Label
        NPOffsetEdit                    matlab.ui.control.NumericEditField
        NPOffsetsecEditFieldLabel       matlab.ui.control.Label
        MarkButton                      matlab.ui.control.Button
        EventDescriptionEditField       matlab.ui.control.EditField
        EventDescriptionEditFieldLabel  matlab.ui.control.Label
        EventTypeDropDown               matlab.ui.control.DropDown
        EventTypeDropDownLabel          matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        EventsButton                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        GoProCheckBox                   matlab.ui.control.CheckBox
        NPAxes                          matlab.ui.control.UIAxes
        VidAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        vid_pupil; %pupil video
        fr_pupil; %pupil frame rate
        aud_pupil; %pupil audio player
        aud_data_pupil; %pupil audio data
        ntp_pupil; %pupil ntp times (sec)
        cf_pupil; %pupil current frame

        vid_gp; %gopro video
        fr_gp; %gopro frame rate
        aud_gp; %gopro audio player
        aud_data_gp; %gopro audio data
        ntp_gp; %gopro ntp times (sec)
        ntp_gp_orig; %original gopro ntp times without the offset applied
        cf_gp; %gopro current frame

        d_np; %neuropace data
        fs_np; %neuropace sampling frequency
        ntp_np; %neuropace ntp times (sec)
        ntp_np_orig; %original neuropace ntp times without the offset applied
        cs_np; %neuropace current sample
        line_np; %line object representing current time in neuropace raster
        plot_np; %plot object for neuropace raster

        d_xs; %xsens pelvic velocity data
        fs_xs; %xsens sample rate (100Hz)
        ntp_xs; 
        ntp_xs_orig;
        cs_xs;
        plot_xs;

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        d_amb; %ambient data (i.e. luminance)
        fs_amb; %ambient sample rate (15Hz)
        ntp_amb; 
        cs_amb; %current sample
        plot_amb;
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        d_gaze_x; %x-gaze location in pixels
        d_gaze_y; %y-gaze location in pixels
        d_gaze_fix; %gaze data (counts up from 1 for each newly detected gaze OR logical true if fixation)
        fs_gaze; %gaze sample rate (200Hz), roughly 4x the rate of the pupil world video
        ntp_gaze; 
        cs_gaze;
        plot_gaze;
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        d_imu; %structure of accel, gyro, roll, and pitch data
        fs_imu; %imu sample rate (200Hz), roughly 4x the rate of the pupil world video
        ntp_imu; 
        cs_imu;
        plot_imu;
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        d_kde;
        fs_kde;
        ntp_kde;
        cs_kde;
        plot_kde;
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        d_bio;
        fs_bio;
        ntp_bio;
        cs_bio;
        plot_bio;
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        vid_img; %current image object displayed on VidAxes
        vid_gaze; %current x/y location of gaze
        cf; %current frame (either pupil or gopro based on GoProCheckBox)
        cntp; %current ntp time (based on either current pupil or gopro frame, gets set in updateFcn)

        evnts_tbl; %table of marked events
        file_list; %list of all data files used in gui

        RWN_EventViewer; %instance of event viewer gui so methods can be executed from this gui
        NPAxesXLim;
        NPAxesYLim;
    end
    
    methods (Access = public)
        
        function updateFcn(app)
            if app.GoProCheckBox.Value
                app.cf_gp = app.cf; %current frame (cf) iterates with left/right arrows in keypress callback
                if app.cf_gp>=1 && app.cf_gp<=app.vid_gp.NumFrames
                    app.cntp = app.ntp_gp(app.cf_gp); %current ntp
                end

                [~,app.cf_pupil] = min(abs(app.cntp-app.ntp_pupil)); %current pupil frame
                [~,app.cs_np] = min(abs(app.cntp-app.ntp_np)); %current neuropace data sample (ntp_np has offset added, ntp_np_orig does not)
                [~,app.cs_gaze] = min(abs(app.cntp-app.ntp_gaze));

                if app.cf_gp>=1 && app.cf_gp<=app.vid_gp.NumFrames
                    app.vid_img.CData = read(app.vid_gp,app.cf_gp);
                    if ~isempty(app.d_gaze_x)
                        app.vid_gaze.XData = app.d_gaze_x(app.cs_gaze);
                        app.vid_gaze.YData = app.d_gaze_y(app.cs_gaze);
                        if app.d_gaze_fix(app.cs_gaze)>0
                            app.vid_gaze.Color = 'g';
                        else
                            app.vid_gaze.Color = 'r';
                        end
                    end
                end

                title(app.VidAxes,sprintf('Frame = %0.0f',app.cf_gp))
            else
                app.cf_pupil = app.cf;
                if app.cf_pupil>=1 && app.cf_pupil<=app.vid_pupil.NumFrames
                    app.cntp = app.ntp_pupil(app.cf_pupil);
                end

                [~,app.cf_gp] = min(abs(app.cntp-app.ntp_gp)); %current gopro frame (ntp_gp has offset added, ntp_gp_orig does not)
                [~,app.cs_np] = min(abs(app.cntp-app.ntp_np)); %current neuropace data sample (ntp_np has offset added, ntp_np_orig does not)
                [~,app.cs_gaze] = min(abs(app.cntp-app.ntp_gaze));

                if app.cf_pupil>=1 && app.cf_pupil<=app.vid_pupil.NumFrames
                    app.vid_img.CData = read(app.vid_pupil,app.cf_pupil);
                    if ~isempty(app.d_gaze_x)
                        app.vid_gaze.XData = app.d_gaze_x(app.cs_gaze);
                        app.vid_gaze.YData = app.d_gaze_y(app.cs_gaze);
                        if app.d_gaze_fix(app.cs_gaze)>0
                            app.vid_gaze.Color = 'g';
                        else
                            app.vid_gaze.Color = 'r';
                        end
                    end
                end

                title(app.VidAxes,sprintf('Frame = %0.0f',app.cf_pupil))
            end

            tt = datetime(app.ntp_np(app.cs_np)./(60*60*24),'convertFrom','datenum');
            tt.Format = 'dd-MMM-uuuu HH:mm:ss.SSS';
            xwin = get(app.NPAxes,'xlim');
            if tt>xwin(2)
                os = tt - xwin(1);
                set(app.NPAxes,'xlim',xwin + os)
            elseif tt<xwin(1)
                os = tt - xwin(2);
                set(app.NPAxes,'xlim',xwin + os)
            end
            set(app.line_np,'xdata',[tt,tt])
            title(app.NPAxes,string(tt))
        end
        
        function playbackFcn(app, varargin)
            sync_diff = app.aud_pupil.CurrentSample/app.aud_pupil.SampleRate - app.vid_pupil.CurrentTime; %if positive, audio leads by sync_diff seconds and frame number needs to increase
            if app.SyncAudCkb.Value
                if abs(sync_diff)>0.2
                    disp('updating audio sync...')
                    app.cf = app.cf+round(app.vid_pupil.FrameRate*sync_diff);
                else
                    app.cf = app.cf+1;
                end
            else
                app.cf = app.cf+1;
            end
            app.updateFcn;
        end

    end
    
    methods (Access = private)
        
        function updateTableFcn(app, varargin)
            for k=1:size(app.evnts_tbl,1)
                [~,app.evnts_tbl.GoProFrame(k)] = min(abs(app.evnts_tbl.NTP(k)-app.ntp_gp));
                [~,app.evnts_tbl.NPSample(k)] = min(abs(app.evnts_tbl.NTP(k)-app.ntp_np));
            end
            if isprop(app.RWN_EventViewer,'UIFigure')
                app.RWN_EventViewer.updateTableFcn;
            end
        end
        
        function selNPAxesLim(app, varargin)
            xlim(app.NPAxes,app.NPAxesXLim);
            ylim(app.NPAxes,app.NPAxesYLim);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %place figure at center of screen
            scrsize = get(0,'screensize');
            winpos = app.UIFigure.Position;
            if any(scrsize(3:4)<winpos(3:4))
                app.UIFigure.WindowState = 'maximized';
            else
                app.UIFigure.Position = [(scrsize(3)-winpos(3))/2,(scrsize(4)-winpos(4))/2,winpos(3),winpos(4)];
            end
            tb = axtoolbar(app.NPAxes,{'export','pan','zoomin','zoomout','restoreview'});
            rv = findobj(tb.Children,'tag','restoreview');
            rv.ButtonPushedFcn = @app.selNPAxesLim;
            app.EventTypeDropDown.Items = {'Doorway','Clapper','LED','Walk Beg','Walk End',...
                'Outdoor Beg','Outdoor End','Landmark Beg','Landmark End','Talking Beg','Talking End',...
                'Correct Turn Beg','Correct Turn End','Incorrect Turn Beg','Incorrect Turn End','Lost Beg',...
                'Lost End','Stop Beg','Stop End','Abnormal Beg','Abnormal End','Pointing Beg',...
                'Pointing End','Notes Beg','Notes End','Choice Point','Stare Beg','Stare End',...
                'Held Door Beg','Held Door End','New Context Beg','New Context End',...
                'Body Turn Beg','Body Turn End'};
            warning('off','MATLAB:table:RowsAddedExistingVars');
            warning('off','MATLAB:table:ModifiedAndSavedVarnames');
        end

        % Button pushed function: LoadButton
        function LoadButtonFcn(app, event)
            WaitH = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Loading...');

            app.UIFigure.Name = '';
            app.GoProOffsetEdit.Value = 0;
            app.NPOffsetEdit.Value = 0;
            app.XSOffsetEdit.Value = 0;

            if isobject(app.vid_pupil)
                delete(app.vid_pupil);
            end
            app.vid_pupil = []; %pupil video
            app.fr_pupil = []; %pupil frame rate
            if isobject(app.aud_pupil)
                delete(app.aud_pupil);
            end
            app.aud_pupil = []; %pupil audio player
            app.aud_data_pupil = []; %pupil audio data
            app.ntp_pupil = []; %pupil ntp times (sec)
            app.cf_pupil = []; %pupil current frame
    
            if isobject(app.vid_gp)
                delete(app.vid_gp);
            end
            app.vid_gp = []; %gopro video
            app.fr_gp = []; %gopro frame rate
            if isobject(app.aud_gp)
                delete(app.aud_gp);
            end
            app.aud_gp = []; %gopro audio player
            app.aud_data_gp = []; %gopro audio data
            app.ntp_gp = []; %gopro ntp times (sec)
            app.ntp_gp_orig = []; %original gopro ntp times without the offset applied
            app.cf_gp = []; %gopro current frame
    
            app.d_np = []; %neuropace data
            app.fs_np = []; %neuropace sampling frequency
            app.ntp_np = []; %neuropace ntp times (sec)
            app.ntp_np_orig = []; %original neuropace ntp times without the offset applied
            app.cs_np = []; %neuropace current sample
            if ishandle(app.line_np)
                delete(app.line_np);
            end
            app.line_np = []; %line object representing current time in neuropace raster
            if all(ishandle(app.plot_np))
                delete(app.plot_np);
            end
            app.plot_np = []; %plot object for neuropace raster

            app.d_xs = []; %xsens data
            app.fs_xs = []; %xsens sampling frequency
            app.ntp_xs = []; %xsens ntp times (sec)
            app.ntp_xs_orig = []; %original xsens ntp times without the offset applied
            app.cs_xs = []; %xsens current sample
            if all(ishandle(app.plot_xs))
                delete(app.plot_xs);
            end
            app.plot_xs = []; %plot object for xsens raster

            %%%%%%%%%%%% Ambient %%%%%%%%%%%%%%
            app.d_amb = []; %ambient/luminance data
            app.fs_amb = []; %amb sampling frequency
            app.ntp_amb = []; %amb ntp times (sec)
            app.cs_amb = []; %amb current sample
            if all(ishandle(app.plot_amb))
                delete(app.plot_amb);
            end
            app.plot_amb = []; %plot object for amb raster
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%% Gaze %%%%%%%%%%%%%%
            app.d_gaze_x = [];
            app.d_gaze_y = [];
            app.d_gaze_fix = []; %gaze data (counts up from 1 for each new gaze period OR boolean, does not match number of pupil frames - roughly 4x the frame rate)
            app.fs_gaze = []; %gaze sampling frequency (200Hz)
            app.ntp_gaze = []; %gaze ntp times (sec) 
            app.cs_gaze = []; %gaze current sample 
            if all(ishandle(app.plot_gaze))
                delete(app.plot_gaze);
            end
            app.plot_gaze = []; %plot object for gaze raster
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%% IMU %%%%%%%%%%%%%%%%%
            app.d_imu = []; %pupil imu data including accel, gyro, roll, pitch (contains head movements)
            app.fs_imu = []; %pupil imu sampling frequency 
            app.ntp_imu = []; %pupil imu ntp times (sec)
            app.cs_imu = []; %pupil imu current sample
            if all(ishandle(app.plot_imu))
                delete(app.plot_imu);
            end
            app.plot_imu = []; %plot object for imu raster
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%% KDEs %%%%%%%%%%%%%%%%%
            app.d_kde = []; %student identified events
            app.fs_kde = []; %kde sampling frequency (i.e. gopro synced frame rate)
            app.ntp_kde = []; %kde ntp times (sec)
            app.cs_kde = []; %kde current sample
            if all(ishandle(app.plot_kde))
                delete(app.plot_kde);
            end
            app.plot_kde = []; %plot object for kde raster
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%% Biopac %%%%%%%%%%%%%%%%%
            app.d_bio = []; %biopac data including skin conductance, accel, heart rate
            app.fs_bio = []; %biopac sampling frequency 
            app.ntp_bio = []; %biopac ntp times (sec)
            app.cs_bio = []; %biopac current sample
            if all(ishandle(app.plot_bio))
                delete(app.plot_bio);
            end
            app.plot_bio = []; %plot object for kde raster
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            if ishandle(app.vid_img)
                delete(app.vid_img);
            end
            app.vid_img = []; %current image object displayed on VidAxes
            app.cf = []; %current frame (either pupil or gopro based on GoProCheckBox)
            app.cntp = []; %current ntp time (based on either current pupil or gopro frame, gets set in updateFcn)
    
            app.evnts_tbl = []; %table of marked events
            app.file_list = []; %list of all data files used in gui

            if isobject(app.RWN_EventViewer)
                delete(app.RWN_EventViewer);
            end
            app.RWN_EventViewer = [];

            cla(app.NPAxes);
            app.NPAxes.XAxis = matlab.graphics.axis.decorator.NumericRuler;
            set(app.NPAxes,'xtick',[],'xticklabel',[],'ytick',[],'yticklabel',[])
            app.NPAxes.XColor = 'none'; app.NPAxes.YColor = 'none'; 
            title(app.NPAxes,'');
            
            cla(app.VidAxes);
            set(app.VidAxes,'xtick',[],'xticklabel',[],'ytick',[],'yticklabel',[])
            app.VidAxes.XColor = 'none'; app.VidAxes.YColor = 'none';
            title(app.VidAxes,'');

            %Finding files
            walk_dir = lastDir('Select "Walk" folder.');
            app.file_list = findRWAFiles(walk_dir, WaitH);

            if isfile(app.file_list.save_file)
                SS = load(app.file_list.save_file);

                app.evnts_tbl = SS.evnts_tbl;
                app.evnts_tbl = sortrows(app.evnts_tbl,{'NTP'});

                app.ntp_pupil = SS.ntp_pupil;
                app.fr_pupil = SS.fr_pupil;

                app.ntp_gp = SS.ntp_gp;
                app.ntp_gp_orig = SS.ntp_gp_orig;
                if round((app.ntp_gp(1)-app.ntp_gp_orig(1))*100)/100==round(SS.offset_gp*100)/100
                    app.GoProOffsetEdit.Value = SS.offset_gp;
                else
                    app.GoProOffsetEdit.Value = round((app.ntp_gp(1)-app.ntp_gp_orig(1))*100)/100;
                    disp('GoPro offset from file does not match calculated. Using calculated offset...')
                end
                app.fr_gp = SS.fr_gp;
                ntp_diff = diff(app.ntp_gp)-(1/app.fr_gp);
                if min(ntp_diff)<-0.0001 || max(ntp_diff)>0.0001 %if more than 1/10ms jitter, send alert
                    disp('GP ntp time vector has too much variance! Need to check...');
                end

                app.ntp_np = SS.ntp_np;
                app.ntp_np_orig = SS.ntp_np_orig;
                if round((app.ntp_np(1)-app.ntp_np_orig(1))*100)/100==round(SS.offset_np*100)/100
                    app.NPOffsetEdit.Value = SS.offset_np;
                else
                    app.NPOffsetEdit.Value = round((app.ntp_np(1)-app.ntp_np_orig(1))*100)/100;
                    disp('NP offset from file does not match calculated. Using calculated offset...')
                end
                app.d_np = SS.d_np;
                app.fs_np = SS.fs_np;
                ntp_diff = diff(app.ntp_np)-(1/app.fs_np);
                if min(ntp_diff)<-0.0001 || max(ntp_diff)>0.0001 %if more than 1/10ms jitter, send alert
                    disp('NP ntp time vector has too much variance! Need to check...');
                end

                if isfield(SS,'ntp_xs')
                    if isempty(SS.ntp_xs)
                        WaitH.Message = 'Loading xsens data...';
                        [app.ntp_xs_orig,app.d_xs,app.fs_xs] = findXsensNTP(app.file_list.xsens_dir,app.UIFigure,WaitH);
                        app.ntp_xs = app.ntp_xs_orig; %this is modified when offset is changed
                    else
                        app.ntp_xs = SS.ntp_xs;
                        app.ntp_xs_orig = SS.ntp_xs_orig;
                        if isempty(app.ntp_xs)
                            app.XSOffsetEdit.Value = SS.offset_xs;
                        else
                            if round((app.ntp_xs(1)-app.ntp_xs_orig(1))*100)/100==round(SS.offset_xs*100)/100
                                app.XSOffsetEdit.Value = SS.offset_xs;
                            else
                                app.XSOffsetEdit.Value = round((app.ntp_xs(1)-app.ntp_xs_orig(1))*100)/100;
                                disp('Xsens offset from file does not match calculated. Using calculated offset...')
                            end
                        end
                        app.d_xs = SS.d_xs;
                        app.fs_xs = SS.fs_xs;
                        if ~isempty(app.ntp_xs)
                            ntp_diff = diff(app.ntp_xs)-(1/app.fs_xs);
                            if min(ntp_diff)<-0.0001 || max(ntp_diff)>0.0001 %if more than 1/10ms jitter, send alert
                                disp('XS ntp time vector has too much variance! Need to check...');
                            end
                        end
                    end
                else
                    WaitH.Message = 'Loading xsens data...';
                    [app.ntp_xs_orig,app.d_xs,app.fs_xs] = findXsensNTP(app.file_list.xsens_dir,app.UIFigure,WaitH);
                    app.ntp_xs = app.ntp_xs_orig; %this is modified when offset is changed
                end

                %%%%%%%%%%%%%% Ambient %%%%%%%%%%%%%%%%%%%%%%%%
                if isfield(SS,'ntp_amb')
                    if isempty(SS.ntp_amb)
                        WaitH.Message = 'Loading ambient data...';
                        [app.ntp_amb,app.d_amb,app.fs_amb] = findPupilAmbNTP(app.file_list,app.UIFigure,WaitH);
                    else
                        app.ntp_amb = SS.ntp_amb;
                        app.d_amb = SS.d_amb;
                        app.fs_amb = SS.fs_amb;
                        if ~isempty(app.ntp_amb)
                            ntp_diff = diff(app.ntp_amb)-(1/app.fs_amb);
                            if min(ntp_diff)<-0.0001 || max(ntp_diff)>0.0001 %if more than 1/10ms jitter, send alert
                                disp('Ambient ntp time vector has too much variance! Need to check...');
                            end
                        end
                    end
                else
                    WaitH.Message = 'Loading ambient data...';
                    try
                        [app.ntp_amb,app.d_amb,app.fs_amb] = findPupilAmbNTP(app.file_list,app.UIFigure,WaitH);
                    catch
                        WaitH.Message = 'Failed to load ambient data!';
                        app.ntp_amb = [];
                        app.d_amb = [];
                        app.fs_amb = [];
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%%%% Gaze %%%%%%%%%%%%%%%%%%%%%%%%
                if isfield(SS,'ntp_gaze')
                    if isempty(SS.ntp_gaze)
                        WaitH.Message = 'Loading gaze data...';
                        [app.ntp_gaze,app.d_gaze_x,app.d_gaze_y,app.d_gaze_fix,app.fs_gaze] = findPupilGazeNTP(app.file_list,app.UIFigure,WaitH);
                    else
                        app.ntp_gaze = SS.ntp_gaze;
                        app.d_gaze_x = SS.d_gaze_x;
                        app.d_gaze_y = SS.d_gaze_y;
                        app.d_gaze_fix = SS.d_gaze_fix;
                        app.fs_gaze = SS.fs_gaze;
                        if ~isempty(app.ntp_gaze)
                            ntp_diff = diff(app.ntp_gaze)-(1/app.fs_gaze);
                            if min(ntp_diff)<-0.0001 || max(ntp_diff)>0.0001 %if more than 1/10ms jitter, send alert
                                disp('Gaze ntp time vector has too much variance! Need to check...');
                            end
                        end
                    end
                else
                    WaitH.Message = 'Loading gaze data...';
                    try
                        [app.ntp_gaze,app.d_gaze_x,app.d_gaze_y,app.d_gaze_fix,app.fs_gaze] = findPupilGazeNTP(app.file_list,app.UIFigure,WaitH);
                    catch
                        WaitH.Message = 'Failed to load gaze data!';
                        app.ntp_gaze = [];
                        app.d_gaze_x = [];
                        app.d_gaze_y = [];
                        app.d_gaze_fix = [];
                        app.fs_gaze = [];
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%% Pupil IMU %%%%%%%%%%%%%%%%%%%%%%%%
                if isfield(SS,'ntp_imu')
                    if isempty(SS.ntp_imu)
                        WaitH.Message = 'Loading Pupil IMU data...';
                        [app.ntp_imu,app.d_imu,app.fs_imu] = findPupilIMUNTP(app.file_list,app.UIFigure,WaitH);
                    else
                        app.ntp_imu = SS.ntp_imu;
                        app.d_imu = SS.d_imu;
                        app.fs_imu = SS.fs_imu;
                        if ~isempty(app.ntp_imu)
                            ntp_diff = diff(app.ntp_imu)-(1/app.fs_imu);
                            if min(ntp_diff)<-0.0001 || max(ntp_diff)>0.0001 %if more than 1/10ms jitter, send alert
                                disp('Pupil IMU ntp time vector has too much variance! Need to check...');
                            end
                        end
                    end
                else
                    WaitH.Message = 'Loading Pupil IMU data...';
                    try
                        [app.ntp_imu,app.d_imu,app.fs_imu] = findPupilIMUNTP(app.file_list,app.UIFigure,WaitH);
                    catch
                        WaitH.Message = 'Failed to load Biopac data!';
                        app.ntp_imu = [];
                        app.d_imu = [];
                        app.fs_imu = [];
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%% KDEs %%%%%%%%%%%%%%%%%%%%%%%%
                if isfield(SS,'ntp_kde')
                    if isempty(SS.ntp_kde)
                        WaitH.Message = 'Loading KDE data...';
                        [app.ntp_kde,app.d_kde,app.fs_kde] = findKDEsNTP(app.file_list,app.UIFigure,WaitH);
                    else
                        app.ntp_kde = SS.ntp_kde;
                        app.d_kde = SS.d_kde;
                        app.fs_kde = SS.fs_kde;
                        if ~isempty(app.ntp_kde)
                            ntp_diff = diff(app.ntp_kde)-(1/app.fs_kde);
                            if min(ntp_diff)<-0.0001 || max(ntp_diff)>0.0001 %if more than 1/10ms jitter, send alert
                                disp('KDE ntp time vector has too much variance! Need to check...');
                            end
                        end
                    end
                else
                    WaitH.Message = 'Loading KDE data...';
                    try
                        [app.ntp_kde,app.d_kde,app.fs_kde] = findKDEsNTP(app.file_list,app.UIFigure,WaitH);
                    catch
                        WaitH.Message = 'Failed to load KDE data!';
                        app.ntp_kde = [];
                        app.d_kde = [];
                        app.fs_kde = [];
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%% Biopac %%%%%%%%%%%%%%%%%%%%%%%%
                if isfield(SS,'ntp_bio')
                    if isempty(SS.ntp_bio)
                        WaitH.Message = 'Loading Biopac data...';
                        [app.ntp_bio,app.d_bio,app.fs_bio] = findBiopacNTP(app.file_list,app.UIFigure,WaitH);
                    else
                        app.ntp_bio = SS.ntp_bio;
                        app.d_bio = SS.d_bio;
                        app.fs_bio = SS.fs_bio;
                        if ~isempty(app.ntp_bio)
                            ntp_diff = diff(app.ntp_bio)-(1/app.fs_bio);
                            if min(ntp_diff)<-0.0001 || max(ntp_diff)>0.0001 %if more than 1/10ms jitter, send alert
                                disp('Biopac ntp time vector has too much variance! Need to check...');
                            end
                        end
                    end
                else
                    WaitH.Message = 'Loading Biopac data...';
                    try
                        [app.ntp_bio,app.d_bio,app.fs_bio] = findBiopacNTP(app.file_list,app.UIFigure,WaitH);
                    catch
                        WaitH.Message = 'Failed to load Biopac data!';
                        app.ntp_bio = [];
                        app.d_bio = [];
                        app.fs_bio = [];
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                WaitH.Message = 'Loading pupil audio data from file...';  
                [y,Fs] = audioread(app.file_list.pupil_vid_file);
                app.aud_pupil = audioplayer(y,Fs);
                WaitH.Message = 'Loading pupil video data from file...';
                app.vid_pupil = VideoReader(app.file_list.pupil_vid_file);

                WaitH.Message = 'Loading gopro audio data from file...';
%                 [y,Fs] = audioread(app.file_list.gopro_vid_file);
%                 app.aud_gp = audioplayer(y,Fs);
                WaitH.Message = 'Loading gopro video data from file...';
                app.vid_gp = VideoReader(app.file_list.gopro_vid_file);
                              
            else
                %Loading data/timestamps
                WaitH.Message = 'Loading pupil data...';
                [app.ntp_pupil,app.vid_pupil,app.aud_pupil,app.aud_data_pupil] = findPupilVidNTP(app.file_list.pupil_vid_file,app.file_list.drift_csv_file,app.file_list.pupil_ts_file);

                WaitH.Message = 'Loading gopro data...';
                [app.ntp_gp_orig,app.vid_gp,app.aud_gp,app.aud_data_gp] = findGoProVidNTP(app.file_list.gopro_vid_file,app.ntp_pupil,app.aud_data_pupil);
                app.ntp_gp = app.ntp_gp_orig; %this is modified when offset is changed

                WaitH.Message = 'Loading neuropace data...';
                [app.ntp_np_orig,app.d_np,app.fs_np] = findNPaceNTP_RW1_Adv(app.file_list, app.UIFigure); %this works for all participants now
                app.ntp_np = app.ntp_np_orig; %this is modified when offset is changed

                WaitH.Message = 'Loading xsens data...';
                try
                    [app.ntp_xs_orig,app.d_xs,app.fs_xs] = findXsensNTP(app.file_list.xsens_dir,app.UIFigure,WaitH);
                catch
                    WaitH.Message = 'Failed to load xsens data!';
                    app.ntp_xs_orig = [];
                    app.d_xs = [];
                    app.fs_xs = [];
                end
                app.ntp_xs = app.ntp_xs_orig; %this is modified when offset is changed

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                WaitH.Message = 'Loading ambient data...';
                try
                    [app.ntp_amb,app.d_amb,app.fs_amb] = findPupilAmbNTP(app.file_list,app.UIFigure,WaitH);
                catch
                    WaitH.Message = 'Failed to load ambient data!';
                    app.ntp_amb = [];
                    app.d_amb = [];
                    app.fs_amb = [];
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                WaitH.Message = 'Loading gaze data...';
                try
                    [app.ntp_gaze,app.d_gaze_x,app.d_gaze_y,app.d_gaze_fix,app.fs_gaze] = findPupilGazeNTP(app.file_list,app.UIFigure,WaitH);
                catch
                    WaitH.Message = 'Failed to load gaze data!';
                    app.ntp_gaze = [];
                    app.d_gaze_x = [];
                    app.d_gaze_y = [];
                    app.d_gaze_fix = [];
                    app.fs_gaze = [];
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                WaitH.Message = 'Loading Pupil IMU data...';
                try
                    [app.ntp_imu,app.d_imu,app.fs_imu] = findPupilIMUNTP(app.file_list,app.UIFigure,WaitH);
                catch
                    WaitH.Message = 'Failed to load Pupil IMU data!';
                    app.ntp_imu = [];
                    app.d_imu = [];
                    app.fs_imu = [];
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                WaitH.Message = 'Loading KDE data...';
                try
                    [app.ntp_kde,app.d_kde,app.fs_kde] = findKDEsNTP(app.file_list,app.UIFigure,WaitH);
                catch
                    WaitH.Message = 'Failed to load KDE data!';
                    app.ntp_kde = [];
                    app.d_kde = [];
                    app.fs_kde = [];
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                WaitH.Message = 'Loading Biopac data...';
                try
                    [app.ntp_bio,app.d_bio,app.fs_bio] = findBiopacNTP(app.file_list,app.UIFigure,WaitH);
                catch
                    WaitH.Message = 'Failed to load Biopac data!';
                    app.ntp_bio = [];
                    app.d_bio = [];
                    app.fs_bio = [];
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %Initialize events table
                app.evnts_tbl = table('Size',[0,6],'VariableTypes',{'string','string','double','double','double','double'},'VariableNames',{'Event','Description','PupilFrame','GoProFrame','NPSample','NTP'});
            end

            close(WaitH);

            % Initializing time variables. Frame is the basis for all
            % timing/synchronization. Right/left arrows iterate throught
            % frames. Frames are either pupil or gopro based on gopro
            % checkbox. All other positions are then calculated using the
            % current frame. GUI defaults to pupil frames on start.
            app.cf_pupil = round((app.ntp_np(1)-app.ntp_pupil(1))*app.vid_pupil.frameRate); %video starts 1st so fast forward to start of data
            if app.cf_pupil<1
                app.cf_pupil = 1; %current frame
            end
            app.cf = app.cf_pupil; %cf is iterated in the keypress callback and assigned to pupil or gopro based on gopro checkbox (pupil is default)
            app.cntp = app.ntp_pupil(app.cf_pupil); %current ntp time
            [~,app.cf_gp] = min(abs(app.cntp-app.ntp_gp)); %current gopro frame
            [~,app.cs_np] = min(abs(app.cntp-app.ntp_np)); %current neuropace data sample
            [~,app.cs_gaze] = min(abs(app.cntp-app.ntp_gaze)); %current gaze data sample (used to plot circle in image for gaze location)

            %Calculating power
            [B,A] = butter(3,[5,8]/(app.fs_np/2),'bandpass');
            pwr_np = app.d_np;
            pwr_np(isnan(pwr_np)) = 0;
            pwr_np(pwr_np<-500) = 0;
            pwr_np = filtfilt(B,A,pwr_np);
            pwr_np = hilbert(pwr_np); %bipolar seeg
            pwr_np = abs(pwr_np).^2;
            pwr_np = smoothdata(pwr_np,'movmean',1000);
            pwr_np = pwr_np - mean(pwr_np);

            %Plotting raster
            dt_np = datetime(app.ntp_np./(60*60*24),'convertFrom','datenum'); 
            app.plot_np = plot(app.NPAxes,dt_np,app.d_np-(0:3)*1000);
%             app.plot_np = plot(app.NPAxes,dt_np,pwr_np-(0:3)*1000);
            hold(app.NPAxes,'on');

            %%%%%%%%%% Adding other data streams to raste %%%%%%%%%%
            % dt_xs = datetime(app.ntp_xs./(60*60*24),'convertFrom','datenum'); 
            % app.plot_xs = plot(app.NPAxes,dt_xs,app.d_xs*250-(4)*1000);
            % 
            % dt_amb = datetime(app.ntp_amb./(60*60*24),'convertFrom','datenum'); 
            % app.plot_amb = plot(app.NPAxes,dt_amb,app.d_amb./30-(4)*1000);
            % 
            % dt_gaze = datetime(app.ntp_gaze./(60*60*24),'convertFrom','datenum'); 
            % app.plot_gaze = plot(app.NPAxes,dt_gaze,~isnan(app.d_gaze).*50-(4)*1000);
            % 
            % dt_kde = datetime(app.ntp_kde./(60*60*24),'convertFrom','datenum'); 
            % app.plot_kde = plot(app.NPAxes,dt_kde,app.d_kde.*100-(4)*1000);
            % 
            % dt_bio = datetime(app.ntp_bio./(60*60*24),'convertFrom','datenum'); 
            % app.plot_bio = plot(app.NPAxes,dt_bio,vecnorm([app.d_bio.X,app.d_bio.Y,app.d_bio.Z],2,2).*200-(4)*1000);
            % 
            % dt_imu = datetime(app.ntp_imu./(60*60*24),'convertFrom','datenum'); 
            % app.plot_imu = plot(app.NPAxes,dt_imu,vecnorm([app.d_imu.accelX,app.d_imu.accelY,app.d_imu.accelZ],2,2).*200-(4)*1000);
            % app.plot_imu = plot(app.NPAxes,dt_imu,(app.d_imu.gyroY).*2-(4)*1000); %x-axis (left/right), y-axis (inf/sup), z-axis (ant/post) -> y-axis represents head rotation
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.NPAxesXLim = [dt_np(1),dt_np(end)];
            app.NPAxesYLim = [-4500,500];
            xlim(app.NPAxes,app.NPAxesXLim);
            ylim(app.NPAxes,app.NPAxesYLim);
            app.NPAxes.XColor = 'k';
            tt = dt_np(app.cs_np); tt.Format = 'dd-MMM-uuuu HH:mm:ss.SSS';
            app.line_np = plot(app.NPAxes,[tt,tt],[-4500,500],'k');
            set(app.NPAxes,'ytick',[],'yticklabel',[]);
            title(app.NPAxes,string(tt))
            
            %Plotting video
            img = read(app.vid_pupil,app.cf_pupil);
            app.vid_img = image(img,'parent',app.VidAxes);
            app.d_gaze_x(app.d_gaze_x<1) = 1; app.d_gaze_x(app.d_gaze_x>app.vid_pupil.Width) = app.vid_pupil.Width;
            app.d_gaze_y(app.d_gaze_y<1) = 1; app.d_gaze_y(app.d_gaze_y>app.vid_pupil.Height) = app.vid_pupil.Height;
            hold(app.VidAxes,'on')
            app.vid_gaze = plot(app.VidAxes,app.d_gaze_x(app.cs_gaze),app.d_gaze_y(app.cs_gaze),'or','MarkerSize',10,'LineWidth',2);
            axis(app.VidAxes,'image')
            set(app.VidAxes,'xtick',[],'xticklabel',[],'ytick',[],'yticklabel',[]);
            title(app.VidAxes,sprintf('Frame = %0.0f',app.cf_pupil))

            %Setting timer for real-time video playback
            app.aud_pupil.TimerFcn = @app.playbackFcn;
            app.aud_pupil.TimerPeriod = 1/app.vid_pupil.FrameRate;

            app.aud_gp.TimerFcn = @app.playbackFcn;
            app.aud_gp.TimerPeriod = 1/app.vid_gp.FrameRate;

            app.UIFigure.Name = walk_dir;
        end

        % Key press function: UIFigure
        function KeyPressFcn(app, event)
            keystr = event.Key;
            if isempty(event.Modifier)
                modstr = [];
            else
                modstr = event.Modifier{1,1};
            end
            switch keystr
                case 'rightarrow'
                    if strcmp(modstr,'shift')
                        app.cf = app.cf+app.FrameSkipEdit.Value;
                    else
                        app.cf = app.cf+1;
                    end
                case 'leftarrow'
                    if strcmp(modstr,'shift')
                        app.cf = app.cf-app.FrameSkipEdit.Value;
                    else
                        app.cf = app.cf-1;
                    end
            end %switch
            app.updateFcn;
        end

        % Value changed function: GoProCheckBox
        function GoProCheckBoxFcn(app, event)
            if app.PlayButton.Value
                app.GoProCheckBox.Value = event.PreviousValue;
                return;
            end
            if app.GoProCheckBox.Value
                app.cf = app.cf_gp;
            else
                app.cf = app.cf_pupil;
            end
            app.updateFcn;
        end

        % Value changed function: GoProOffsetEdit
        function GoProOffsetEditFcn(app, event)
            app.ntp_gp = app.ntp_gp_orig + app.GoProOffsetEdit.Value;
            if app.GoProCheckBox.Value
                [~,app.cf] = min(abs(app.cntp-app.ntp_gp));
            end
            app.updateFcn;
            app.updateTableFcn;
        end

        % Value changed function: NPOffsetEdit
        function NPOffsetEditFcn(app, event)
            app.ntp_np = app.ntp_np_orig + app.NPOffsetEdit.Value;
            dt_np = datetime(app.ntp_np./(60*60*24),'convertFrom','datenum');
            for k=1:length(app.plot_np)
                app.plot_np(k).XData = dt_np;
            end
            app.updateFcn; %vertical "current time" line is updated in this function
            app.updateTableFcn;
        end

        % Value changed function: XSOffsetEdit
        function XSOffsetEditFcn(app, event)
            app.ntp_xs = app.ntp_xs_orig + app.XSOffsetEdit.Value;
            dt_xs = datetime(app.ntp_xs./(60*60*24),'convertFrom','datenum');
            app.plot_xs.XData = dt_xs;
            app.updateFcn; %vertical "current time" line is updated in this function
            app.updateTableFcn;
        end

        % Value changed function: GoToEditField
        function GoToEditFieldFcn(app, event)
            %10:32:36.985
            ts = app.GoToEditField.Value;
            tt = datetime(app.ntp_np(app.cs_np)./(60*60*24),'convertFrom','datenum'); 
            tt.Format = 'dd-MMM-uuuu';
            ts_sec = datenum(datetime([char(tt),' ',ts]))*60*60*24;
            if app.GoProCheckBox.Value
                [~,app.cf] = min(abs(app.ntp_gp-ts_sec));
            else
                [~,app.cf] = min(abs(app.ntp_pupil-ts_sec));
            end
            app.updateFcn;
        end

        % Button down function: NPAxes
        function AxesButtonDownFcn(app, event)
            dt = app.NPAxes.XAxis.Limits(1);
            dt.Hour = 0;
            dt.Minute = 0;
            dt.Second = 0;
            %Current point is fraction of current day (i.e. 0.45 - very strange!)
            ts_sec = datenum(dt+days(event.IntersectionPoint(1)))*60*60*24;
            if app.GoProCheckBox.Value
                [~,app.cf] = min(abs(app.ntp_gp-ts_sec));
            else
                [~,app.cf] = min(abs(app.ntp_pupil-ts_sec));
            end
            app.updateFcn;
        end

        % Button pushed function: SaveButton
        function SaveButtonFcn(app, event)
            app.updateTableFcn;
            app.SaveButton.BackgroundColor = 'r';
            SS = [];

            SS.evnts_tbl = sortrows(app.evnts_tbl,{'NTP'});

            SS.ntp_pupil = app.ntp_pupil;
            SS.fr_pupil = app.vid_pupil.FrameRate;
            
            SS.ntp_gp = app.ntp_gp;
            SS.ntp_gp_orig = app.ntp_gp_orig;
            SS.offset_gp = app.GoProOffsetEdit.Value;
            SS.fr_gp = app.vid_gp.FrameRate;

            SS.ntp_np = app.ntp_np;
            SS.ntp_np_orig = app.ntp_np_orig;
            SS.offset_np = app.NPOffsetEdit.Value;
            SS.d_np = app.d_np;
            SS.fs_np = app.fs_np;

            SS.ntp_xs = app.ntp_xs;
            SS.ntp_xs_orig = app.ntp_xs_orig;
            SS.offset_xs = app.XSOffsetEdit.Value;
            SS.d_xs = app.d_xs;
            SS.fs_xs = app.fs_xs;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Amb,Gaze,KDE,Bio,IMU data not saved to original RWNApp*.mat
            %files. Need to add them at some point or just skip and add the
            %data using updateRWNAppFiles.
            % SS.ntp_gaze = app.ntp_gaze;
            % SS.d_gaze_x = app.d_gaze_x;
            % SS.d_gaze_y = app.d_gaze_y;
            % SS.d_gaze_fix = app.d_gaze_fix;
            % SS.fs_gaze = app.fs_gaze;
            % 
            % SS.ntp_amp = app.ntp_amb;
            % SS.d_amb = app.d_amb;
            % SS.fs_amb = app.fs_amb;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            SS.file_list = app.file_list;

            save(app.file_list.save_file,'-struct',"SS");
            app.SaveButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: MarkButton
        function MarkButtonFcn(app, event)
            nrows = size(app.evnts_tbl,1);
            app.evnts_tbl.Event(nrows+1) = app.EventTypeDropDown.Value;
            app.evnts_tbl.Description(nrows+1) = app.EventDescriptionEditField.Value;
            app.EventDescriptionEditField.Value = '';
            app.evnts_tbl.PupilFrame(nrows+1) = app.cf_pupil;
            app.evnts_tbl.GoProFrame(nrows+1) = app.cf_gp;
            app.evnts_tbl.NPSample(nrows+1) = app.cs_np;
            app.evnts_tbl.NTP(nrows+1) = app.cntp;
            app.evnts_tbl = sortrows(app.evnts_tbl,{'NTP'});
            if isprop(app.RWN_EventViewer,'UIFigure')
                app.RWN_EventViewer.updateTableFcn;
            end
        end

        % Value changed function: PlayButton
        function PlayButtonFcn(app, event)
            if event.PreviousValue
                app.PlayButton.Value = true;
                return;
            end
            if app.GoProCheckBox.Value
                app.PlayButton.Value = false;
                uialert(app.UIFigure,'Deselect GoPro Checkbox!','');
                return;
            end
            play(app.aud_pupil,round((app.cf_pupil/app.vid_pupil.FrameRate)*app.aud_pupil.SampleRate)); %0.25sec starts audio earlier to account for delay in starting video timer
%             if app.GoProCheckBox.Value
%                 play(app.aud_gp,round((app.cf_gp/app.vid_gp.FrameRate - 0.25)*app.aud_gp.SampleRate));
%             else
%                 play(app.aud_pupil,round((app.cf_pupil/app.vid_pupil.FrameRate - 0.25)*app.aud_pupil.SampleRate)); %0.25sec starts audio earlier to account for delay in starting video timer
%             end
            app.PlayButton.BackgroundColor = 'g';
        end

        % Value changed function: StopButton
        function StopButtonFcn(app, event)
            if app.GoProCheckBox.Value
                stop(app.aud_gp);
            else
                stop(app.aud_pupil);
            end
            app.StopButton.Value = false;
            app.PlayButton.Value = false;
            app.PlayButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: EventsButton
        function EventsButtonFcn(app, event)
            RWNApp_EventViewer(app);
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            if isprop(app.RWN_EventViewer,'UIFigure')
                delete(app.RWN_EventViewer);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.VidAxes = uiaxes(app.UIFigure);
            app.VidAxes.XTick = [];
            app.VidAxes.YTick = [];
            app.NPAxes = uiaxes(app.UIFigure);
            app.NPAxes.XTick = [];
            app.NPAxes.YTick = [];
            app.NPAxes.ButtonDownFcn = createCallbackFcn(app, @AxesButtonDownFcn, true);
            app.GoProCheckBox = uicheckbox(app.UIFigure);
            app.GoProCheckBox.ValueChangedFcn = createCallbackFcn(app, @GoProCheckBoxFcn, true);
            app.GoProCheckBox.Tag = 'GoProCkb';
            app.GoProCheckBox.Tooltip = {'Display GoPro video'};
            app.GoProCheckBox.Text = 'GoPro';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonFcn, true);
            app.LoadButton.Tag = 'LoadBtn';
            app.LoadButton.Tooltip = {'Load data'};
            app.LoadButton.Text = 'Load';
            app.EventsButton = uibutton(app.UIFigure, 'push');
            app.EventsButton.ButtonPushedFcn = createCallbackFcn(app, @EventsButtonFcn, true);
            app.EventsButton.Tag = 'EventsBtn';
            app.EventsButton.Tooltip = {'Open events table for editing'};
            app.EventsButton.Text = 'Events';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonFcn, true);
            app.SaveButton.Tooltip = {'Save events table and ntp times to file'};
            app.SaveButton.Text = 'Save';
            app.EventTypeDropDownLabel = uilabel(app.UIFigure);
            app.EventTypeDropDownLabel.Text = 'Event Type';
            app.EventTypeDropDown = uidropdown(app.UIFigure);
            app.EventTypeDropDown.Items = {'Doorway'};
            app.EventTypeDropDown.Tag = 'EventTypeDd';
            app.EventTypeDropDown.Tooltip = {'Choose event type'};
            app.EventTypeDropDown.Value = 'Doorway';
            app.EventDescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.EventDescriptionEditFieldLabel.Text = 'Event Description';
            app.EventDescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.EventDescriptionEditField.Tag = 'EventDescEdit';
            app.EventDescriptionEditField.Tooltip = {'Describe event in detail'};
            app.MarkButton = uibutton(app.UIFigure, 'push');
            app.MarkButton.ButtonPushedFcn = createCallbackFcn(app, @MarkButtonFcn, true);
            app.MarkButton.Tag = 'MarkBtn';
            app.MarkButton.Tooltip = {'Mark event'};
            app.MarkButton.Text = 'Mark';
            app.NPOffsetsecEditFieldLabel = uilabel(app.UIFigure);
            app.NPOffsetsecEditFieldLabel.Text = 'NP Offset (sec)';
            app.NPOffsetEdit = uieditfield(app.UIFigure, 'numeric');
            app.NPOffsetEdit.ValueChangedFcn = createCallbackFcn(app, @NPOffsetEditFcn, true);
            app.NPOffsetEdit.Tooltip = {'Add an offset to align neuropace data with pupil video'};
            app.GoProOffsetsecEditFieldLabel = uilabel(app.UIFigure);
            app.GoProOffsetsecEditFieldLabel.Text = 'GoPro Offset (sec)';
            app.GoProOffsetEdit = uieditfield(app.UIFigure, 'numeric');
            app.GoProOffsetEdit.ValueChangedFcn = createCallbackFcn(app, @GoProOffsetEditFcn, true);
            app.GoProOffsetEdit.Tooltip = {'Add an offset to align GoPro video with pupil video. An increase will move frames backwards in time.'};
            app.GoTohhmmssmsEditFieldLabel = uilabel(app.UIFigure);
            app.GoTohhmmssmsEditFieldLabel.Text = 'GoTo (hh:mm:ss.ms)';
            app.GoToEditField = uieditfield(app.UIFigure, 'text');
            app.GoToEditField.ValueChangedFcn = createCallbackFcn(app, @GoToEditFieldFcn, true);
            app.SkipframesLabel = uilabel(app.UIFigure);
            app.SkipframesLabel.Text = 'Skip (frames)';
            app.FrameSkipEdit = uieditfield(app.UIFigure, 'numeric');
            app.FrameSkipEdit.Tooltip = {'Shift+right/left arrow skip size in frames.'};
            app.FrameSkipEdit.Value = 120;
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonFcn, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonFcn, true);
            app.PlayButton.Text = 'Play';
            app.XSOffsetsecLabel = uilabel(app.UIFigure);
            app.XSOffsetsecLabel.Text = 'XS Offset (sec)';
            app.XSOffsetEdit = uieditfield(app.UIFigure, 'numeric');
            app.XSOffsetEdit.ValueChangedFcn = createCallbackFcn(app, @XSOffsetEditFcn, true);
            app.XSOffsetEdit.Tooltip = {'Add an offset to align neuropace data with pupil video'};
            app.SyncAudCkb = uicheckbox(app.UIFigure);
            app.SyncAudCkb.Tag = 'GoProCkb';
            app.SyncAudCkb.Tooltip = {'Display GoPro video'};
            app.SyncAudCkb.Text = 'Sync Audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RWNApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end